classdef global_peaks_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        GridLayout6                     matlab.ui.container.GridLayout
        Refine                          matlab.ui.control.CheckBox
        SavePreviewButton               matlab.ui.control.Button
        PreviewFindButton               matlab.ui.control.Button
        PlotROIButton                   matlab.ui.control.Button
        ClearROIButton                  matlab.ui.control.Button
        SmthSpanLabel                   matlab.ui.control.Label
        SmthSpanSpinner                 matlab.ui.control.Spinner
        ShowROICheckBox                 matlab.ui.control.CheckBox
        DefineROIButton                 matlab.ui.control.Button
        ZoningDropDown_2Label           matlab.ui.control.Label
        ZoningEditFieldLabel            matlab.ui.control.Label
        UpperLimitEditFieldLabel        matlab.ui.control.Label
        UpperLimitEditField             matlab.ui.control.NumericEditField
        LowerLimitEditFieldLabel        matlab.ui.control.Label
        LowerLimitEditField             matlab.ui.control.NumericEditField
        ParameterDropDownLabel          matlab.ui.control.Label
        ParameterDropDown               matlab.ui.control.DropDown
        SeedingDropDown                 matlab.ui.control.DropDown
        SeedingDropDownLabel            matlab.ui.control.Label
        SmoothingDropDownLabel          matlab.ui.control.Label
        ZoningDropDown                  matlab.ui.control.DropDown
        SmoothingDropDown               matlab.ui.control.DropDown
        GeneratingDropDownLabel         matlab.ui.control.Label
        GeneratingDropDown              matlab.ui.control.DropDown
        PartneringDropDownLabel         matlab.ui.control.Label
        PartneringDropDown              matlab.ui.control.DropDown
        SmthDegreeSpinnerLabel          matlab.ui.control.Label
        ZoningEditField                 matlab.ui.control.EditField
        SmthDegreeSpinner               matlab.ui.control.Spinner
        OfPeaksSpinnerLabel             matlab.ui.control.Label
        OfPeaksSpinner                  matlab.ui.control.Spinner
        ThresholdLabel                  matlab.ui.control.Label
        ThresholdEditField              matlab.ui.control.NumericEditField
        MinDistEditFieldLabel           matlab.ui.control.Label
        MinDistEditField                matlab.ui.control.NumericEditField
        ToleranceEditFieldLabel         matlab.ui.control.Label
        ToleranceEditField              matlab.ui.control.NumericEditField
        ShiftingEditFieldLabel          matlab.ui.control.Label
        ShiftingEditField               matlab.ui.control.NumericEditField
        YcoordinateLabel                matlab.ui.control.Label
        Ycoordinate                     matlab.ui.control.Spinner
        XcoordinateSpinnerLabel         matlab.ui.control.Label
        Xcoordinate                     matlab.ui.control.Spinner
        SaveAllButton                   matlab.ui.control.Button
        PerformPeakFindingButton        matlab.ui.control.Button
        ShowPeaksSwitch                 matlab.ui.control.Switch
        ShowRawSwitch                   matlab.ui.control.Switch
        ShowRawRedLabel                 matlab.ui.control.Label
        SaveButton                      matlab.ui.control.Button
        LoadDataButton                  matlab.ui.control.Button
        LoadedListBox                   matlab.ui.control.ListBox
        ShowPeaksSwitchLabel            matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        PeakFindingTab                  matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        chunksCheckBox                  matlab.ui.control.CheckBox
        SavetoBaseWorkspaceButton       matlab.ui.control.Button
        NormalizeCountsButton           matlab.ui.control.StateButton
        PreviousSettingsButton          matlab.ui.control.StateButton
        RightButton                     matlab.ui.control.Button
        LeftButton                      matlab.ui.control.Button
        GenChunkButton                  matlab.ui.control.Button
        ofChunksEditField               matlab.ui.control.NumericEditField
        LockLaplacianButton             matlab.ui.control.StateButton
        HighlightErrorsButton           matlab.ui.control.StateButton
        LaplacianThresholdLabel         matlab.ui.control.Label
        LineCutDropDown                 matlab.ui.control.DropDown
        DisplayAxes                     matlab.ui.control.UIAxes
        SpectrumAxes                    matlab.ui.control.UIAxes
        PixelDetailsTab                 matlab.ui.container.Tab
        FileDataTable                   matlab.ui.control.Table
        PixelValueTable                 matlab.ui.control.Table
        PixelValueAxes                  matlab.ui.control.UIAxes
        GlobalActionsTab                matlab.ui.container.Tab
        OffsetEditField                 matlab.ui.control.NumericEditField
        OffsetEditFieldLabel            matlab.ui.control.Label
        DisplayAlignmentSwitch          matlab.ui.control.Switch
        DisplayAlignmentSwitchLabel     matlab.ui.control.Label
        DifferencefromIdealSwitch       matlab.ui.control.Switch
        DifferencefromIdealSwitchLabel  matlab.ui.control.Label
        FilestoUseListBoxLabel          matlab.ui.control.Label
        FilestoUseListBox               matlab.ui.control.ListBox
        PlottedValueDropDown            matlab.ui.control.DropDown
        PlottedValueDropDownLabel       matlab.ui.control.Label
        PassDownSplitButton             matlab.ui.control.Button
        InterceptLabel                  matlab.ui.control.Label
        SlopeLabel                      matlab.ui.control.Label
        CalculateSlopesButton           matlab.ui.control.Button
        SavePlotDataButton              matlab.ui.control.Button
        AlignImagesButton               matlab.ui.control.Button
        SlopeFileDataTable              matlab.ui.control.Table
        SlopeAxes                       matlab.ui.control.UIAxes
        SlopeMapAxes                    matlab.ui.control.UIAxes
        ScriptRunsTab                   matlab.ui.container.Tab
        GridLayout5                     matlab.ui.container.GridLayout
        OutputFilesListBox              matlab.ui.control.ListBox
        OutputFilesListBoxLabel         matlab.ui.control.Label
        InputFilesListBox               matlab.ui.control.ListBox
        InputFilesListBoxLabel          matlab.ui.control.Label
        UploadButton                    matlab.ui.control.Button
        AvailableScriptsListBox         matlab.ui.control.ListBox
        AvailableScriptsListBoxLabel    matlab.ui.control.Label
        ApplyButton                     matlab.ui.control.Button
        PreviewButton                   matlab.ui.control.Button
        ScriptDisplayAxes               matlab.ui.control.UIAxes
        ScriptOutputAxes                matlab.ui.control.UIAxes
        ConfocalCodeTab                 matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        OrientationButton               matlab.ui.control.StateButton
        Button_4                        matlab.ui.control.StateButton
        FitButton                       matlab.ui.control.Button
        Button_3                        matlab.ui.control.StateButton
        Button_2                        matlab.ui.control.StateButton
        Button                          matlab.ui.control.StateButton
        ExportButton                    matlab.ui.control.Button
        UpdateButton                    matlab.ui.control.Button
        ParametersTable                 matlab.ui.control.Table
        StressAxes                      matlab.ui.control.UIAxes
        FunPlotsTab                     matlab.ui.container.Tab
        ColormapEditorButton            matlab.ui.control.Button
        LaplacianAxes                   matlab.ui.control.UIAxes
        VerticalSpectrumAxes            matlab.ui.control.UIAxes
        HorizontalSpectrumAxes          matlab.ui.control.UIAxes
        Axes3D                          matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        current_random_index = 1
        random_coords = [[1, 1]]
        xcoord
        ycoord
        x_lines
        y_lines
        parameter_labels = ["D_{111} (Hz)", "E_{111} (Hz)", "Width (σ)", ... 
                        "111 Width Deviation (σ)", "111 Contrast (%)", ... 
                        "111 Contrast Deviation", "D_{non111} (Hz)", ... 
                        "E_{non111}", "Non-111 Width (σ)", ...
                        "Non-111 Width Deviation", "Non-111 Contrast (%)", ...
                        "Non-111 Contrast Deviation", 'Baseline (Hz)', ...
                        'Tries', 'Left Peak', 'Right Peak', 'Laplacian'];
        all_gWides = struct("signal", {}, "SweepParam", {})
        all_peak_info_dfs = {}
        all_parameter_dfs = {}
        all_reflection_dfs = {}
        all_preview_dfs = {}
        roi_object
        roi_mask
        % Create new all_settings struct when file loaded in with current
        % settings, whenever peak find run save the settings for that file 
        % and toggle in between them, much like all_reflection_dfs
        all_settings = struct("shift", {}, "tolerance", {}, ...
            "partnering_method", {}, "num_max_peaks", {}, ...
             "peak_perc_threshold", {}, "diff_peak_distance", {}, "refine", {}, ...
             "smooth_span", {}, "smooth_degree", {}, ...
            "smoothing_method", {}, "generating_method", {}, ...
            "seeding_method", {}, "normalize", {}, "peak_max", {}, ...
            "lock_flag", {}, "zones_x", {}, "zones_y", {}, "zoning_method", {}, ...
            "roi_object", {}, "roi_mask", {})
        x_coords
        slope_df
        lock_laplacian = 0
        user_script_paths
        all_script_results = {}
    end
    
    methods (Access = private)
        function params_struct = get_params(app)
            str = app.ZoningEditField.Value;
            % Parse string with regex (accounting for exponentials + Inf)
            numberPattern = '(?:-?\d+\.?\d*(?:[eE][+-]?\d+)?|-?Inf)';
            numberStrings = regexp(str, numberPattern, 'match');
            numbers = str2double(numberStrings);
            hasPairs = contains(str, '(') && contains(str, ')');
            if hasPairs && mod(length(numbers), 2) == 0
                x = numbers(1:2:end);
                y = numbers(2:2:end);
            else
                if app.ZoningDropDown.ValueIndex == 3 || app.ZoningDropDown.ValueIndex == 5
                    % If vals or split store in y-coordinate
                    y = numbers;
                    x = [0, Inf];
                else
                    x = numbers;
                    y = [0, Inf];
                end
            end
            updateROIMask(app);

            % holy
            % This is an input to both user defined and peak find scripts
            params_struct = struct( ...
                "shift", app.ShiftingEditField.Value, ...
                "tolerance", app.ToleranceEditField.Value, ...
                "partnering_method", app.PartneringDropDown.ValueIndex, ...
                "num_max_peaks", app.OfPeaksSpinner.Value, ...
                "peak_perc_threshold", app.ThresholdEditField.Value, ...
                "diff_peak_distance", app.MinDistEditField.Value, ...
                "refine", app.Refine.Value, ...
                "smooth_span", app.SmthSpanSpinner.Value, ...
                "smooth_degree", app.SmthDegreeSpinner.Value, ...
                "smoothing_method", app.SmoothingDropDown.ValueIndex, ...,
                "generating_method", app.GeneratingDropDown.ValueIndex, ...
                "seeding_method", app.SeedingDropDown.ValueIndex, ...
                "normalize", app.NormalizeCountsButton.Value, ...
                "lock_flag", app.LockLaplacianButton.Value, ...
                "zones_x", x, ...
                "zones_y", y, ...
                "zoning_method", app.ZoningDropDown.ValueIndex, ...
                "peak_max", Inf, ...
                "roi_object", app.roi_object, ...
                "roi_mask", app.roi_mask);
        end

        function set_params(app, params, i)
           app.ShiftingEditField.Value = params.shift;
           app.ToleranceEditField.Value = params.tolerance;
           app.PartneringDropDown.ValueIndex = params.partnering_method;
           app.OfPeaksSpinner.Value = params.num_max_peaks;
           app.ThresholdEditField.Value = params.peak_perc_threshold;
           app.MinDistEditField.Value = params.diff_peak_distance;
           app.Refine.Value = params.refine;
           app.SmthSpanSpinner.Value = params.smooth_span;
           app.SmthDegreeSpinner.Value = params.smooth_degree;
           app.SmoothingDropDown.ValueIndex = params.smoothing_method;
           app.GeneratingDropDown.ValueIndex = params.generating_method;
           app.SeedingDropDown.ValueIndex = params.seeding_method;
           app.NormalizeCountsButton.Value = params.normalize;
           if(isfield(params, "roi_object"))
               app.roi_object = params.roi_object;
               app.roi_mask = params.roi_mask;
           else
               app.roi_object = [];
               app.roi_mask = [];
               params.roi_object = [];
               params.roi_mask = [];
           end
           if(isfield(params, "zones_x"))
               if(isempty(params.zones_y))
                   numList = params.zones_x(:)';      
                   pairs = arrayfun(@(i) sprintf('[%d,%d]', numList(i), numList(i+1)), 1:2:length(numList)-1, 'UniformOutput', false);    
                   str = strjoin(pairs, ',');
               elseif(params.zones_y ~= [0, Inf])
                   str = sprintf('[%g,%g],', params.zones_x, params.zones_y);
                   str(end) = []; % Remove the trailing comma
               else
                   numList = params.zones_x(:)';      
                   pairs = arrayfun(@(i) sprintf('[%d,%d]', numList(i), numList(i+1)), 1:2:length(numList)-1, 'UniformOutput', false);    
                   str = strjoin(pairs, ',');
               end
               app.ZoningEditField.Value = str;
               app.ZoningDropDown.ValueIndex = params.zoning_method;
           else
               params.zones_x = [];
               params.zones_y = [];
               params.zoning_method = 1;
           end
           app.all_settings(i) = params;
        end

        function plot_data(app)
           global gWide
           cla(app.DisplayAxes);
           data=squeeze(gWide.signal(:,:,2))';
           if app.NormalizeCountsButton.Value
               for x=1:size(data, 2)
                   for y=1:size(data, 1)
                       raw = squeeze(gWide.signal(x, y, :));
                       raw=raw-mean(raw);
                       raw=raw/std(raw);
                       data(y, x) = max(raw);
                   end
               end
           end
           im = imagesc(app.DisplayAxes, data); % Display the slice
           im.HitTest = 'off';
           colorbar(app.DisplayAxes);
           clim(app.DisplayAxes, 'auto')
           lims = clim(app.DisplayAxes);
           app.LowerLimitEditField.Value = lims(1);
           app.UpperLimitEditField.Value = lims(2);
           xlim(app.DisplayAxes,[1 size(data,2)])
           ylim(app.DisplayAxes,[1 size(data,1)])
           pbaspect(app.DisplayAxes,[1 size(data,1)/size(data,2) 1]);
           title(app.DisplayAxes, "Initial Signal Value")
           
           cla(app.ScriptDisplayAxes);
           data=squeeze(gWide.signal(:,:,2))';
           if app.NormalizeCountsButton.Value
               for x=1:size(data, 2)
                   for y=1:size(data, 1)
                       raw = squeeze(gWide.signal(x, y, :));
                       raw=raw-mean(raw);
                       raw=raw/std(raw);
                       data(y, x) = max(raw);
                   end
               end
           end
           im = imagesc(app.ScriptDisplayAxes, data); % Display the slice
           im.HitTest = 'off';
           colorbar(app.ScriptDisplayAxes);
           clim(app.ScriptDisplayAxes, 'auto')
           lims = clim(app.ScriptDisplayAxes);
           app.LowerLimitEditField.Value = lims(1);
           app.UpperLimitEditField.Value = lims(2);
           xlim(app.ScriptDisplayAxes,[1 size(data,2)])
           ylim(app.ScriptDisplayAxes,[1 size(data,1)])
           pbaspect(app.ScriptDisplayAxes,[1 size(data,1)/size(data,2) 1]);
           title(app.ScriptDisplayAxes, "Initial Signal Value")
            
           app.Xcoordinate.LowerLimitInclusive = 1;
           app.Ycoordinate.LowerLimitInclusive = 1;
           app.Xcoordinate.UpperLimitInclusive = size(data, 2);
           app.Ycoordinate.UpperLimitInclusive = size(data, 1);
           plot_signal(app);
           replot_crosshair(app, app.Xcoordinate.Value, app.Ycoordinate.Value)
        end

        function plot_signal(app, xin, yin)
            global gWide parameters_dataframe;
            % This is a doozy
            % Get data from gWides
            if isempty(gWide)
                return;
            end
            data=squeeze(gWide.signal(:,:,2))';

            % Should be able to peak find and display
            if nargin < 2
               x = app.Xcoordinate.Value;
               y = app.Ycoordinate.Value;
               xin = x;
               yin = y;
            else
                x = xin;
                y = yin;
            end
            if xin > size(data, 2)
                x = size(data, 2) - 1;
            end
            if yin > size(data, 2)
                y = size(data, 2) - 1;
            end

            % If taking line cut make the x and ys arrays
            if app.LineCutDropDown.ValueIndex == 2 % Vertical = fixed x
                y = 1:size(data, 2) - 1;
            elseif app.LineCutDropDown.ValueIndex == 3 % horizontal = fixed y
                x = 1:size(data, 1) - 1;
            end

            data = gWide.signal;
            freq = gWide.SweepParam;
            if nnz(freq) == 0
                table_signal(app);
                spectrum(app);
                return
            end
            smooth_span = app.SmthSpanSpinner.Value;
            smooth_degree = app.SmthDegreeSpinner.Value;
            % Plot the signal on the spectrum axes
            cla(app.SpectrumAxes);
            hold(app.SpectrumAxes, 'on');
            switch app.LineCutDropDown.Value
                case "Pixel"
                    raw = squeeze(data(x, y, :));
                    if app.NormalizeCountsButton.Value
                        raw=raw-mean(raw);
                        raw=raw/std(raw);
                    end
                    signal = smooth(raw, smooth_span, 'sgolay', smooth_degree);
                    lims = signal;
                    if strcmp(app.ShowRawSwitch.Value, "On")
                        plot(app.SpectrumAxes, freq, raw, 'Color', 'r')
                        if min(raw) < min(signal) && max(raw) > max(signal) 
                            lims = raw;
                        end
                    end
                    set(app.SpectrumAxes,'color', [1 1 1]);
                    plot(app.SpectrumAxes, freq, signal, 'Color', 'b')

                    if min(lims)==max(lims)
                        ylim(app.SpectrumAxes,[min(lims)-1 max(lims)+1])
                    elseif isnan(min(lims)) || isnan(max(lims))
                        ylim(app.SpectrumAxes, [0, 1])
                    else
                        ylim(app.SpectrumAxes, [min(lims), max(lims)])
                    end
                    app.SpectrumAxes.YDir = 'normal';
                    ylabel(app.SpectrumAxes,'Counts')

                case "Vertical"
                    raw=squeeze(data(x,:,:));
                    % Normalize data
                    for i=1:size(raw,1)
                        raw(i,:)=raw(i,:)-mean(raw(i,:));
                        raw(i,:)=raw(i,:)/std(raw(i,:));
                    end
                    if app.ShowRawSwitch.ValueIndex ~= 2 % raw is off => smooth
                        for ysmooth=1:size(raw, 1)
                            raw(ysmooth, :) = smooth(raw(ysmooth, :), smooth_span, 'sgolay', smooth_degree);
                        end
                    end
                    if app.HighlightErrorsButton.Value
                        if ~isempty(parameters_dataframe)
                            laplacian = parameters_dataframe(:, x, 17);
                            laplacian = repmat(laplacian, 1, length(freq));
                            set(app.SpectrumAxes,'color', [1 0 0]);
                            imagesc(app.SpectrumAxes, freq, 1:size(raw,1), raw, "AlphaData", 1 - 0.5 * laplacian);
                        else
                            imagesc(app.SpectrumAxes, freq, 1:size(raw,1),raw,[min(raw(:)) max(raw(:))]);
                        end
                    else
                        imagesc(app.SpectrumAxes, freq, 1:size(raw,1),raw,[min(raw(:)) max(raw(:))]);
                    end
                    yline(app.SpectrumAxes, yin);
                    ylim(app.SpectrumAxes,[1 size(raw,1)]);
                    ylabel(app.SpectrumAxes,'Pixel');
                    app.SpectrumAxes.YDir = 'reverse';

                case "Horizontal"
                    raw=squeeze(data(:,y,:));
                    % Normalize data
                    for i=1:size(raw,1)
                        raw(i,:)=raw(i,:)-mean(raw(i,:));
                        raw(i,:)=raw(i,:)/std(raw(i,:));
                    end
                    if app.ShowRawSwitch.ValueIndex ~= 2 % raw is off => smooth
                        for xsmooth=1:size(raw, 1)
                            raw(xsmooth, :) = smooth(raw(xsmooth, :), smooth_span, 'sgolay', smooth_degree);
                        end
                    end
                    if app.HighlightErrorsButton.Value
                        if ~isempty(parameters_dataframe)
                            laplacian = parameters_dataframe(y, :, 17);
                            laplacian = repmat(laplacian', 1, length(freq));
                            set(app.SpectrumAxes,'color', [1 0 0]);
                            imagesc(app.SpectrumAxes, freq, 1:size(raw,1), raw, "AlphaData", 1 - 0.5 * laplacian);
                        else
                            imagesc(app.SpectrumAxes, freq, 1:size(raw,1),raw,[min(raw(:)) max(raw(:))]);
                        end
                    else
                        imagesc(app.SpectrumAxes, freq, 1:size(raw,1),raw,[min(raw(:)) max(raw(:))]);
                    end
                    yline(app.SpectrumAxes, xin);
                    ylim(app.SpectrumAxes,[1 size(raw,1)]);
                    ylabel(app.SpectrumAxes,'Pixel');
                    app.SpectrumAxes.YDir = 'reverse';
            end
            xlim(app.SpectrumAxes, [min(freq), max(freq)])

            % If we want to overlay peaks on top of this
            if app.ShowPeaksSwitch.ValueIndex == 2
                
                % Get necessary parameters
                params_struct = get_params(app);

                % Find peaks
                found_peaks_info = struct("seed", {}, "seed2", {}, "threshold", {}, "index_pairs", {}, ...
                    "sorted_locs", {}, "sorted_vals", {}, "centers", {}, "locs", {}, "vals", {});
                fields = {'seed', 'seed2', 'threshold', 'index_pairs', 'sorted_locs', ...
                    'sorted_vals', 'centers', 'locs', 'vals'};

                data = gWide.signal;
                for xval = x
                    for yval = y
                        [seed, new_peaks_info, peaks_info] = ...
                            peak_find_function(xval, yval, data, freq, params_struct);
                        

                        % Store the return values into a cell array
                        seed2 = 'none'; % will return error if not needed
                        if app.PreviousSettingsButton.Value
                            if ~isempty(parameters_dataframe)
                                seed2 = parameters_dataframe(yval, xval, :);
                            end
                        end
                        output = {seed, seed2, peaks_info.threshold, ...
                                  new_peaks_info.pairs, ...
                                  new_peaks_info.locs, ...
                                  new_peaks_info.vals, ...
                                  new_peaks_info.centers, ...
                                  peaks_info.locs, ...
                                  peaks_info.vals};
                        new_entry = cell2struct(num2cell(output), fields, 2);
                        found_peaks_info(end + 1) = new_entry;
                    end
                end

                % Plot the peaks
                switch app.LineCutDropDown.Value
                    case "Pixel"
                        
                        yline(app.SpectrumAxes, found_peaks_info.threshold{1}, 'Color', 'r')
                        plot(app.SpectrumAxes, found_peaks_info.locs{1}, found_peaks_info.vals{1}, 'rv', 'MarkerFaceColor', 'k');
                        

                        colors = ['r', 'g', 'b', 'c', 'm'];

                        index_pairs = found_peaks_info.index_pairs{1};
                        sorted_locs = found_peaks_info.sorted_locs{1};
                        sorted_vals = found_peaks_info.sorted_vals{1};
                        seed = found_peaks_info.seed{1};
                        seed2 = found_peaks_info.seed2{1};
                        centers = found_peaks_info.centers{1};
                        if isempty(index_pairs)
                            if app.PartneringDropDown.ValueIndex == 1
                                xline(app.SpectrumAxes, params_struct.shift, 'LineWidth', 2) % Provided shift
                            end
                        end
                        shown_partners = zeros(numel(sorted_locs), 1);

                        % disp('bonkc')
                        % assignin('base', 'aag', app.all_gWides(app.LoadedListBox.ValueIndex)); 
                        alb=app.all_gWides(app.LoadedListBox.ValueIndex);
                        if isfield(alb, 'gpu') % Check if this specific index is non-empty
                            
                            [seed, new_peaks_info, peaks_info] = ...
                            peak_find_function(x, y, data, freq, params_struct);
                            gpu_data = alb.gpu; % Extract the GPU data
                            
                            % disp(data(x,y,5));
                            bline=mean(squeeze(data(x, y, :))); %since this is how gpufit does baselines
                            modmod = model_GPU(squeeze(gpu_data(:,y,x)), freq);   % Assuming mod is stored in gpu_data
                            
                            plot(app.SpectrumAxes, freq, bline*modmod, 'LineWidth', 1, 'Color', 'g'); % Plot freq vs mod
                        end

                        for i = 1:size(index_pairs, 1) % For each pair
                            if shown_partners(index_pairs(i, 1)) == 0 && ...
                               shown_partners(index_pairs(i, 2)) == 0 % if we haven't shown the current peak's partner yet
                                shown_partners(index_pairs(i, 1)) = 1;
                                shown_partners(index_pairs(i, 2)) = 1;
                                peak = sorted_locs(index_pairs(i, 1)); % Get the first peak and second peak
                                peak_partner = sorted_locs(index_pairs(i, 2));
                                init_height = sorted_vals(index_pairs(i, 1));
                                second_height = sorted_vals(index_pairs(i, 2));
                                color = colors(mod(i - 1, numel(colors)) + 1); % Give them a color
                                plot(app.SpectrumAxes, peak, init_height, 'rv', 'MarkerFaceColor', color)
                                plot(app.SpectrumAxes, peak_partner, second_height, 'rv', 'MarkerFaceColor', color)
                                text(app.SpectrumAxes, peak_partner + 0.01e9, second_height, num2str(i))
                                text(app.SpectrumAxes, peak + 0.01e9,init_height,num2str(i))

                                if app.PartneringDropDown.ValueIndex == 2
                                    bound = peak + params_struct.shift;
                                    if bound + params_struct.shift > max(freq)
                                        bound = peak - params_struct.shift;
                                    end
                                    xline(app.SpectrumAxes, bound, 'LineWidth', 2, 'Color', color) % In practice shift
                                elseif app.PartneringDropDown.ValueIndex == 1
                                    xline(app.SpectrumAxes, params_struct.shift, 'LineWidth', 2) % Provided shift
                                elseif app.PartneringDropDown.ValueIndex == 3
                                    % nothing, nonsensical
                                end

                                if app.PreviousSettingsButton.Value
                                    if ~isempty(app.all_parameter_dfs)
                                        if numel(app.all_parameter_dfs) >= app.LoadedListBox.ValueIndex % If parameters calculated
                                            if ~isempty(app.all_parameter_dfs{app.LoadedListBox.ValueIndex})
                                                prev_reflection = app.all_reflection_dfs{app.LoadedListBox.ValueIndex};
                                                prev_reflection = prev_reflection(y, x);
                                                xline(app.SpectrumAxes, prev_reflection, 'LineWidth', 2, 'Color', 'y') % previous shift
                                            end
                                        end
                                    end
                                end


                                if peak == seed(15) || peak == seed(16) % If the peak is the current 111 peak, it should be drawn out
                                    center = centers(i);
                                    xline(app.SpectrumAxes, peak)
                                    xline(app.SpectrumAxes, center)
                                    xregion(app.SpectrumAxes, center - params_struct.tolerance, center + params_struct.tolerance, 'FaceColor', color)
                                end
                            end
                        end
                        % Color the past 111 peaks yellow if the user wants
                        % to see them
                        if app.PreviousSettingsButton.Value && numel(seed2) >= 15
                            xline(app.SpectrumAxes, seed2(15), "-y")
                            xline(app.SpectrumAxes, seed2(16), "-y")
                            plot(app.SpectrumAxes, seed2(15), seed2(13) * (seed2(5) - seed2(6) + 1), 'rv', 'MarkerFaceColor', 'y');
                            plot(app.SpectrumAxes, seed2(16), seed2(13) * (seed2(5) + seed2(6) + 1), 'rv', 'MarkerFaceColor', 'y');
                        end
                    case "Horizontal"
                        left_peak = zeros(numel(found_peaks_info), 1);
                        right_peak = zeros(numel(found_peaks_info), 1);
                        left_peak2 = zeros(numel(found_peaks_info), 1);
                        right_peak2 = zeros(numel(found_peaks_info), 1);
                        maxLength = max(cellfun(@length, {found_peaks_info.locs}));
                        all_peaks = nan(length(found_peaks_info), maxLength);

                        for i=1:numel(found_peaks_info)
                            seed = found_peaks_info(1, i).seed;
                            left_peak(i) = seed{1}(15);
                            right_peak(i) = seed{1}(16);
                            locs = found_peaks_info(1, i).sorted_locs{:};
                            locs = union(locs, found_peaks_info(1, i).locs{:});
                            all_peaks(i, 1:length(locs)) = locs;
                        end
                        if app.PreviousSettingsButton.Value 
                            for i=1:numel(found_peaks_info)
                                seed2 = found_peaks_info(1, i).seed2;
                                if ~strcmp(seed2{1}, 'none')
                                    left_peak2(i) = seed2{1}(15);
                                    right_peak2(i) = seed2{1}(16);
                                end
                            end
                        end
                        [xsize, num_peaks] = size(all_peaks);
                        all_peaks(all_peaks == 0) = NaN;
                        colors = ['r', 'g', 'b', 'c', 'm', 'k'];

                        for i=1:num_peaks
                            % if i ~= 1 && i ~= 2
                            %     continue;
                            % end
                            color = colors(mod(i - 1, numel(colors)) + 1);
                            % plot(app.SpectrumAxes, all_peaks(:, i), 1:xsize, 'k');
                            scatter(app.SpectrumAxes, all_peaks(:, i), 1:xsize, 10, color, 'MarkerEdgeColor', color);
                        end
                        left_peak(left_peak == 0) = NaN;
                        left_peak2(left_peak2 == 0) = NaN;

                        plot(app.SpectrumAxes, left_peak, 1:numel(left_peak), right_peak, 1:numel(right_peak), 'Color', 'r')
                        plot(app.SpectrumAxes, left_peak2, 1:numel(left_peak2), right_peak2, 1:numel(right_peak2), 'Color', [1 1 1])
                    case "Vertical"
                        left_peak = zeros(numel(found_peaks_info), 1);
                        right_peak = zeros(numel(found_peaks_info), 1);
                        left_peak2 = zeros(numel(found_peaks_info), 1);
                        right_peak2 = zeros(numel(found_peaks_info), 1);
                        maxLength = max(cellfun(@length, {found_peaks_info.locs}));
                        all_peaks = nan(length(found_peaks_info), maxLength);

                        for i=1:numel(found_peaks_info)
                            seed = found_peaks_info(1, i).seed;
                            left_peak(i) = seed{1}(15);
                            right_peak(i) = seed{1}(16);
                            locs = found_peaks_info(1, i).sorted_locs{:};
                            locs = union(locs, found_peaks_info(1, i).locs{:});
                            all_peaks(i, 1:length(locs)) = locs;
                        end
                        if app.PreviousSettingsButton.Value 
                            for i=1:numel(found_peaks_info)
                                seed2 = found_peaks_info(1, i).seed2;
                                if ~strcmp(seed2{1}, 'none')
                                    left_peak2(i) = seed2{1}(15);
                                    right_peak2(i) = seed2{1}(16);    
                                end
                            end
                        end
                        [ysize, num_peaks] = size(all_peaks);
                        all_peaks(all_peaks == 0) = NaN;
                        colors = ['r', 'g', 'b', 'c', 'm', 'k'];
                        for i=1:num_peaks
                            color = colors(mod(i - 1, numel(colors)) + 1);
                            scatter(app.SpectrumAxes, all_peaks(:, i), 1:ysize, 10, color, 'filled', 'MarkerEdgeColor', color);
                        end

                        plot(app.SpectrumAxes, left_peak, 1:numel(left_peak), right_peak, 1:numel(right_peak), 'Color', 'r')
                        plot(app.SpectrumAxes, left_peak2, 1:numel(left_peak2), right_peak2, 1:numel(right_peak2), 'Color', [1 1 1])
                end
           end

           hold(app.SpectrumAxes, 'off');
           table_signal(app);
           spectrum(app);

           % Stress replot
           data = gWide.signal;
           freq = gWide.SweepParam;
           data = squeeze(data(x, y, :));
           plot(app.StressAxes, freq, data);
           app.ParametersTable.Data(1, 2) = freq(1);
           app.ParametersTable.Data(2, 2) = freq(end);
           app.ParametersTable.Data(3, 2) = numel(freq);
           % stress = parse_stress_params(app);
        end

        function table_signal(app)
            global parameters_dataframe
            x = app.Xcoordinate.Value;
            y = app.Ycoordinate.Value;

            index = app.ParameterDropDown.ValueIndex;
            if index > length(app.parameter_labels)
                index = 1;
            end
            if ~isempty(parameters_dataframe)
                if x > size(parameters_dataframe(:, :, 2), 2)
                    x = size(parameters_dataframe(:, :, 2), 2) - 1;
                end
                if y > size(parameters_dataframe(:, :, 2), 2)
                    y = size(parameters_dataframe(:, :, 2), 2) - 1;
                end
                data = squeeze(parameters_dataframe(y, x, :));
                app.PixelValueTable.Data = [app.parameter_labels', data];
    
                all_data = zeros(size(app.all_parameter_dfs));
                for i=1:numel(app.all_parameter_dfs)
                    curr_param = app.all_parameter_dfs{i};
                    if ~isempty(curr_param)
                        all_data(i) = squeeze(curr_param(y, x, index));
                    end
                end

                if ~isempty(all_data)
                    file_names = 1:size(all_data, 2);
                    sample_x_coords = 1:size(all_data, 2);
                    app.x_coords = sample_x_coords';
                    if ~isempty(app.SlopeFileDataTable.Data)
                        if size(app.x_coords, 1) == size(app.SlopeFileDataTable.Data(:, 3), 1)
                            app.x_coords = app.SlopeFileDataTable.Data(:, 3);
                        end
                    end
                    
                    app.FileDataTable.Data = [file_names', all_data', app.x_coords];
                    app.SlopeFileDataTable.Data = [file_names', all_data', app.x_coords];
                    cla(app.PixelValueAxes)
                    hold(app.PixelValueAxes, 'on');
                    plot(app.PixelValueAxes, app.x_coords, all_data);
                    plot(app.PixelValueAxes, app.x_coords, all_data, 'rv');
                    hold(app.PixelValueAxes, 'off');

                    cla(app.SlopeAxes)
                    hold(app.SlopeAxes, 'on');
                    plot(app.SlopeAxes, app.x_coords, all_data, 'DisplayName', 'Actual');
                    plot(app.SlopeAxes, app.x_coords, all_data, 'rs', 'DisplayName', 'Actual Data');
                    if ~isempty(app.slope_df)
                        slope = squeeze(app.slope_df(y, x, 1));
                        intercept = squeeze(app.slope_df(y, x, 3));
                        equation = intercept + slope * app.x_coords;
                        plot(app.SlopeAxes, app.x_coords, equation, 'DisplayName', 'Fit')
                        plot(app.SlopeAxes, app.x_coords, equation, 'rv', 'DisplayName', 'Fit Data');
                        app.SlopeLabel.Text = "Slope: " + slope;
                        app.InterceptLabel.Text = "Intercept: " + intercept;

                        if app.DifferencefromIdealSwitch.ValueIndex == 2
                            ideal_slope = 28.025 * 1e5 * 24.11;
                            equation = intercept + ideal_slope * app.x_coords;
                            plot(app.SlopeAxes, app.x_coords, equation, 'DisplayName', 'Ideal');
                            plot(app.SlopeAxes, app.x_coords, equation, 'ro', 'DisplayName', 'Ideal Data');
                            app.SlopeLabel.Text = "Slope: " + slope + " vs. " + ideal_slope;
                        end
                    end
                    legend(app.SlopeAxes);
                    hold(app.SlopeAxes, 'off');
                end
            end
        end

        function params = parse_stress_params(app)
            % Parse parameters info from table
            vals = str2double(app.ParametersTable.Data(:, 2));
            params = struct("start", vals(1), "end", vals(2), "num", vals(3), ...
                "ZFS", vals(4), "BB", vals(5), "phi", vals(6), "theta", vals(7), ...
                "width", vals(8), "offset", vals(9), "A1", vals(10), "A2", vals(11), "A3", vals(12), ...
                "A4", vals(13), "EE", vals(14), "phi_E", vals(15), "theta_E", vals(16), "sxxpyy", vals(17), ...
                "sxxnyy", vals(18), "szz", vals(19), "sxz", vals(20), "syz", vals(21), "sxy", vals(22), ...,
                "orientation", app.OrientationButton.Value);
        end

        function surface3d(app)
            global parameters_dataframe
            i = app.ParameterDropDown.ValueIndex;
            if i > numel(app.parameter_labels)
                i = 1;
            end
            if size(parameters_dataframe, 3) < i
                return
            end
            data = parameters_dataframe(:, :, i);
            data(data == 0) = NaN;
            surf(app.Axes3D, data);

            laplacian = del2(data);
            imagesc(app.LaplacianAxes, laplacian);
            colorbar(app.LaplacianAxes);
            clim(app.LaplacianAxes, 'auto');
            pbaspect(app.LaplacianAxes,[1 size(data,1)/size(data,2) 1]);
            xlim(app.LaplacianAxes,[1 size(data,2)])
            ylim(app.LaplacianAxes,[1 size(data,1)])
        end

        function spectrum(app)
            global parameters_dataframe
            if isempty(parameters_dataframe)
                return;
            end
            x = app.Xcoordinate.Value;
            y = app.Ycoordinate.Value;

            if x > size(parameters_dataframe(:, :, 2), 2)
                x = size(parameters_dataframe(:, :, 2), 2) - 1;
            end
            if y > size(parameters_dataframe(:, :, 2), 2)
                y = size(parameters_dataframe(:, :, 2), 2) - 1;
            end

            cla(app.VerticalSpectrumAxes);
            xData1 = parameters_dataframe(:, x, 15);
            xData1(xData1 == 0) = NaN; % don't show it if it's 0
            hold(app.VerticalSpectrumAxes, 'on')
            plot(app.VerticalSpectrumAxes, xData1, 'Color', 'b');
            xData2 = parameters_dataframe(:, x, 16);
            xData2(xData2 == 0) = NaN; % don't show it if it's 0
            plot(app.VerticalSpectrumAxes, xData2, 'Color','r');
            xline(app.VerticalSpectrumAxes, y);
            hold(app.VerticalSpectrumAxes, 'off')

            cla(app.HorizontalSpectrumAxes);
            xData1 = parameters_dataframe(y, :, 15);
            xData1(xData1 == 0) = NaN; % don't show it if it's 0
            hold(app.HorizontalSpectrumAxes, 'on')
            plot(app.HorizontalSpectrumAxes, xData1, 'Color', 'b');
            xData2 = parameters_dataframe(y, :, 16);
            xData2(xData2 == 0) = NaN; % don't show it if it's 0
            plot(app.HorizontalSpectrumAxes, xData2, 'Color','r');
            xline(app.HorizontalSpectrumAxes, x);
            hold(app.HorizontalSpectrumAxes, 'off')
        end

        function align_image_setup(app)
            if ~isempty(app.all_parameter_dfs)
                empty_indices = cellfun(@isempty, app.all_parameter_dfs);
                items = app.LoadedListBox.Items(~empty_indices);
                app.FilestoUseListBox.Items = items;
            end
        end

        function plot_parameter(app, i, parameters_dataframe, axes)
            % if size(parameters_dataframe, 3) < i
            %     return
            % end
            if i > length(app.parameter_labels)
                i = 1;
            end
            data = parameters_dataframe(:, :, i);
            data(data == 0) = NaN;
            set(axes,'color', [1 0 0]);
            vertices = [];
            if ~isempty(app.roi_object) && isvalid(app.roi_object)
                vertices = app.roi_object.Position;
            end
            if app.HighlightErrorsButton.Value
                im = imagesc(axes, data, 'AlphaData', (1 - parameters_dataframe(:, :, 17) * 0.5) .* ~isnan(data)); % Display the slice
            else
                im = imagesc(axes, data, 'AlphaData', ~isnan(data)); % Display the slice
            end
            im.HitTest = 'off';
            clim(axes, "auto");
            colorbar(axes); % Add a colorbar to each subplot
            lims = clim(axes);
            app.LowerLimitEditField.Value = lims(1);
            app.UpperLimitEditField.Value = lims(2);
            xlim(axes,[1 size(data,2)])
            ylim(axes,[1 size(data,1)])
            pbaspect(axes,[1 size(data,1)/size(data,2) 1]);
            app.Xcoordinate.LowerLimitInclusive = 1;
            app.Ycoordinate.LowerLimitInclusive = 1;
            app.Xcoordinate.UpperLimitInclusive = size(data, 2);
            app.Ycoordinate.UpperLimitInclusive = size(data, 1);
            if (isempty(app.roi_object) || ~isvalid(app.roi_object)) && ~isempty(vertices)
                app.roi_object = drawpolygon(app.DisplayAxes, ...
                    'Position', vertices, 'LineWidth', 2, 'Color', 'w');
                drawpolygon(app.ScriptDisplayAxes, 'Position', vertices, 'LineWidth', 2, 'Color', 'w');
            end
            if lims(1) <= lims(2)
                return
            end
            
            hold(axes, 'on');
            title(axes, [app.parameter_labels(i)]); % Add a title to each subplot
            hold(axes, 'off')
            replot_crosshair(app, app.Xcoordinate.Value, app.Ycoordinate.Value)
        end
        
        function replot_crosshair(app, x, y)
            % Clear crosshairs
            app.x_lines = app.x_lines(isgraphics(app.x_lines));
            app.y_lines = app.y_lines(isgraphics(app.y_lines));
            delete(app.x_lines)
            delete(app.y_lines)

            if strcmp(app.ParameterDropDown.Value, "Tries") || strcmp(app.ParameterDropDown.Value, "Laplacian")
                app.x_lines(1) = xline(app.DisplayAxes, x, 'Color','c');
                app.y_lines(1) = yline(app.DisplayAxes, y, 'Color', 'c');
            else
                app.x_lines(1) = xline(app.DisplayAxes, x, 'Color','r');
                app.y_lines(1) = yline(app.DisplayAxes, y, 'Color', 'r');
            end
            app.x_lines(2) = xline(app.SlopeMapAxes, x, 'Color','r');
            app.y_lines(2) = yline(app.SlopeMapAxes, y, 'Color', 'r');
            app.x_lines(3) = xline(app.ScriptDisplayAxes, x, 'Color','r');
            app.y_lines(3) = yline(app.ScriptDisplayAxes, y, 'Color', 'r');
            app.x_lines(4) = xline(app.ScriptOutputAxes, x, 'Color','r');
            app.y_lines(4) = yline(app.ScriptOutputAxes, y, 'Color', 'r');
        end

        function updateROIMask(app)
            [rows, cols] = size(app.all_gWides(app.LoadedListBox.ValueIndex).signal, [1,2]);
            invalid_object = (isempty(app.roi_object) || ~isvalid(app.roi_object) || isempty(app.roi_object.Position));
            if(nnz(app.roi_mask) ~= numel(app.roi_mask) && invalid_object)
                return;
            elseif(invalid_object)
                app.roi_mask = ones(rows, cols);
                return;
            end
            x = round(app.roi_object.Position(:, 1));
            y = round(app.roi_object.Position(:, 2));
            app.roi_mask = poly2mask(x, y, rows, cols);
        end
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clear global
            stress_params_names = ["Start Freq", "End Freq", "Number of Points", ...
                                  "ZFS", "BB", "phi", "theta", "width", ...
                                  "offset", "A1", "A2", "A3", "A4", "EE", ...
                                  "phi_E", "theta_E", "xx + yy", "xx - yy", ...
                                  "zz", "xz", "yz", "xy"];
            stress_params_init = [2.6e9, 3.3e9, 500, 2.87e9, 0, ...
                                  0, 0, 1e7, 1, -2e5, -2e5, -2e5, ...
                                  0, 0, 0, 0, 0, 0, 0, 0, 0, 0];
            fixed = false(1, 22);
            app.ParametersTable.ColumnFormat = {[], 'numeric', 'logical'};
            app.ParametersTable.Data = [stress_params_names', stress_params_init', fixed'];

            % Import user_scripts
            relative_folder = fullfile('gui_backend', 'user_scripts');
            folder = which(relative_folder);
            currentClassName = class(app); % `app` is the app instance passed into callbacks

            % Retrieve the file path of the current app
            appPath = which(currentClassName);

            % Extract the folder path
            [appFolderPath, ~, ~] = fileparts(appPath);
            if(isempty(folder))
                folder = fullfile(appFolderPath, relative_folder);
            else
                folder = fileparts(folder);
            end
            if ~isfolder(folder)        
                uialert(app.UIFigure, sprintf('The folder "%s" does not exist.', folder), 'Folder Not Found');        
            else
                files = dir(fullfile(folder, '*.m'));
                filenames = {files.name};
                filepaths = fullfile({files.folder}, filenames);
                app.user_script_paths = filepaths;
                app.AvailableScriptsListBox.Items = filenames;
                for i=1:length(app.user_script_paths)
                    app.all_script_results{i} = zeros(0,1);
                end
            end
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
           global gWide parameters_dataframe
           [baseFileNames, folders] = uigetfile('.mat','Choose File', 'MultiSelect','on');
           figure(app.UIFigure)
            
           if isequal(baseFileNames, 0)
               % User clicked cancel
               return;
           end
            
           % Ensure baseFileNames is a cell array for consistent processing
           if ischar(baseFileNames)
               baseFileNames = {baseFileNames};
           end

           f = waitbar(0, "Loading...");

           if numel(baseFileNames) == 2 && ...
           ((contains(baseFileNames{1}, 'left') && contains(baseFileNames{2}, 'right')) || ...
           (contains(baseFileNames{1}, 'right') && contains(baseFileNames{2}, 'left')))
               % basefp1=fullfile(folders, baseFileNames{1});
               % basefp2=fullfile(folders, baseFileNames{2});
               combfile=left_right_combiner(baseFileNames{1}, baseFileNames{2}, folders);
               % disp('huh');
               % disp(combfile);
               fullFileName=combfile;
               [~, fileName, fileExt] = fileparts(fullFileName);
               fileNameWithExt = [fileName, fileExt];
               out=regexp(folders,'\','split');
               folder = out(end - 1) + "\";
               halfFileName = folder + fileNameWithExt;
               halfFileName = {char(halfFileName)};
               app.LoadedListBox.Items(end + 1) = halfFileName;
               app.LoadedListBox.Value = halfFileName;
               app.LoadedListBox.ValueIndex = length(app.LoadedListBox.Items);
               app.InputFilesListBox.Items(end + 1) = halfFileName;
               app.InputFilesListBox.Value = halfFileName;
               app.InputFilesListBox.ValueIndex = length(app.InputFilesListBox.Items);
               app.OutputFilesListBox.Items(end + 1) = halfFileName;
               app.OutputFilesListBox.Value = halfFileName;
               app.OutputFilesListBox.ValueIndex = length(app.OutputFilesListBox.Items);
               loadedData = load(fullFileName);
               if isfield(loadedData, 'gWide')
                   % Just a gWide
                   app.all_gWides(end + 1).signal = loadedData.gWide.signal;
                   app.all_gWides(end).SweepParam = loadedData.gWide.SweepParam;
                   app.all_parameter_dfs{length(app.all_gWides)} = zeros(0,1);
                   app.all_reflection_dfs{length(app.all_gWides)} = zeros(0,1);
                   app.all_peak_info_dfs{length(app.all_gWides)} = zeros(0,1);
                   gWide = app.all_gWides(end);
                   
                   params = struct( ...
                       "shift", 5.7e8, ...
                       "tolerance", 1e8, ...
                       "partnering_method", 2, ...
                       "num_max_peaks", 2, ...
                       "peak_perc_threshold", 0, ...
                       "diff_peak_distance", 1e7, ...
                       "refine", 1, ...
                       "smooth_span", 41, ...
                       "smooth_degree", 7, ...
                       "smoothing_method", 1, ...
                       "generating_method", 7, ...
                       "seeding_method", 1, ...
                       "normalize", 0, ...
                       "lock_flag", 0, ...
                       "zones_x", [0, Inf], ...
                       "zones_y", [0, Inf], ...
                       "zoning_method", 1, ...
                       "peak_max", Inf, ...
                       "roi_object", [], ...
                       "roi_mask", []);
                   set_params(app, params, length(app.all_gWides));

                   app.ParameterDropDown.Enable = 'off';
                   app.ParameterDropDownLabel.Enable = 'off';
                   plot_data(app);
               end


           end

           for i = 1:numel(baseFileNames)
               baseFileName = baseFileNames{i};
               out=regexp(folders,'\','split');
               folder = out(end - 1) + "\";
               fullFileName = fullfile(folders, baseFileName);
               halfFileName = folder + baseFileName;
               halfFileName = {char(halfFileName)};
               app.LoadedListBox.Items(end + 1) = halfFileName;
               app.LoadedListBox.Value = halfFileName;
               app.LoadedListBox.ValueIndex = length(app.LoadedListBox.Items);
               app.InputFilesListBox.Items(end + 1) = halfFileName;
               app.InputFilesListBox.Value = halfFileName;
               app.InputFilesListBox.ValueIndex = length(app.InputFilesListBox.Items);
               app.OutputFilesListBox.Items(end + 1) = halfFileName;
               app.OutputFilesListBox.Value = halfFileName;
               app.OutputFilesListBox.ValueIndex = length(app.OutputFilesListBox.Items);

               loadedData = load(fullFileName);
               if isfield(loadedData, 'gWide')
                   % Just a gWide
                   app.all_gWides(end + 1).signal = loadedData.gWide.signal;
                   app.all_gWides(end).SweepParam = loadedData.gWide.SweepParam;
                   app.all_parameter_dfs{length(app.all_gWides)} = zeros(0,1);
                   app.all_reflection_dfs{length(app.all_gWides)} = zeros(0,1);
                   app.all_peak_info_dfs{length(app.all_gWides)} = zeros(0,1);
                   gWide = app.all_gWides(end);
                   
                   params = struct( ...
                       "shift", 5.7e8, ...
                       "tolerance", 1e8, ...
                       "partnering_method", 2, ...
                       "num_max_peaks", 2, ...
                       "peak_perc_threshold", 0, ...
                       "diff_peak_distance", 1e7, ...
                       "refine", 1, ...
                       "smooth_span", 41, ...
                       "smooth_degree", 7, ...
                       "smoothing_method", 1, ...
                       "generating_method", 7, ...
                       "seeding_method", 1, ...
                       "normalize", 0, ...
                       "lock_flag", 0, ...
                       "zones_x", [0, Inf], ...
                       "zones_y", [0, Inf], ...
                       "zoning_method", 1, ...
                       "peak_max", Inf, ...
                       "roi_object", [], ...
                       "roi_mask", []);
                   set_params(app, params, length(app.all_gWides));

                   app.ParameterDropDown.Enable = 'off';
                   app.ParameterDropDownLabel.Enable = 'off';
                   plot_data(app);
               elseif isfield(loadedData, 'all_param_data')
                   % All parameters with the gWide
                   app.all_gWides(end+1).signal = loadedData.all_param_data.gWide.signal;
                   app.all_gWides(end).SweepParam = loadedData.all_param_data.gWide.SweepParam;
                   pdf = loadedData.all_param_data.parameters_dataframe;
                   app.all_parameter_dfs{length(app.all_gWides)} = loadedData.all_param_data.parameters_dataframe;
                   app.all_reflection_dfs{length(app.all_gWides)} = loadedData.all_param_data.reflection_df;
                   if(isfield(loadedData.all_param_data, "peak_info_df"))
                       app.all_peak_info_dfs{length(app.all_gWides)} = loadedData.all_param_data.peak_info_df;
                   else
                       app.all_peak_info_dfs{length(app.all_gWides)} = zeros(0,1);
                   end
                   gWide = app.all_gWides(end);
                   parameters_dataframe = pdf;
                   params = loadedData.all_param_data.params;
                   if ~isfield(params, 'refine')
                       params.refine = 1; % Add the 'refine' field and set it to 1
                       disp('Field "refine" not found in params. Adding it with value 1.');
                   end

                   set_params(app, params, length(app.all_gWides));
                   app.ParameterDropDown.Enable = 'on';
                   app.ParameterDropDownLabel.Enable = 'on';
                   align_image_setup(app);
                   plot_parameter(app, i, pdf, app.DisplayAxes);
                   plot_parameter(app, i, pdf, app.ScriptDisplayAxes);
               elseif isfield(loadedData, 'pdf') || isfield(loadedData, 'data')
                   data = loadedData.data .* 1e9;
                   pdf = zeros([size(data, [1, 2]), 16]);
                   pdf(:, :, 1) = (data(:, :, 2) + data(:, :, 1)) / 2;
                   pdf(:, :, 2) = (data(:, :, 2) - data(:, :, 1)) / 2;
                   pdf(:, :, [15, 16]) = data;
                   pdf = laplacian_calculate(pdf, 0);

                   app.all_gWides(end+1).signal = zeros(size(pdf));
                   app.all_gWides(end).SweepParam = zeros(size(pdf));
                   app.all_parameter_dfs{length(app.all_gWides)} = pdf;
                   app.all_reflection_dfs{length(app.all_gWides)} = zeros(size(pdf, [1, 2]));
                   gWide = app.all_gWides(end);
                   parameters_dataframe = pdf;

                   params = struct( ...
                       "shift", 5.7e8, ...
                       "tolerance", 1e8, ...
                       "partnering_method", 2, ...
                       "num_max_peaks", 2, ...
                       "peak_perc_threshold", 0, ...
                       "diff_peak_distance", 1e7, ...
                       "refine", 1, ...
                       "smooth_span", 41, ...
                       "smooth_degree", 7, ...
                       "smoothing_method", 1, ...
                       "generating_method", 7, ...
                       "seeding_method", 1, ...
                       "normalize", 0, ...
                       "peak_max", Inf, ...
                       "zones_x", [0, Inf], ...
                       "zones_y", [0, Inf], ...
                       "zoning_method", 1, ...
                       "lock_flag", 0, ...
                       "roi_object", [], ...
                       "roi_mask", []);
                   set_params(app, params, length(app.all_gWides));

                   app.ParameterDropDown.Enable = 'on';
                   app.ParameterDropDownLabel.Enable = 'on';
                   align_image_setup(app);
                   plot_parameter(app, i, pdf, app.DisplayAxes);
                   plot_parameter(app, i, pdf, app.ScriptDisplayAxes);
               else
                    uialert(app.UIFigure, "No gWide available in file " + baseFileName + ", load failed", "Error")
                    continue
               end
               waitbar(i / numel(baseFileNames), f, "Loading...")
           end
           waitbar(i / numel(baseFileNames), f, "Completed!")
           close(f)
           figure(app.UIFigure)
        end

        % Value changed function: ShowPeaksSwitch
        function ShowPeaksSwitchValueChanged(app, event)
            plot_signal(app);
        end

        % Value changed function: ShowRawSwitch
        function ShowRawSwitchValueChanged(app, event)
            plot_signal(app);
        end

        % Button pushed function: GenChunkButton
        function GenChunkButtonPushed(app, event)
            global gWide
            app.current_random_index = 1;
            data=squeeze(gWide.signal(:,:,2))';
            xsize = size(data, 2);
            ysize = size(data, 1);
            chunk_size = floor(xsize / app.ofChunksEditField.Value);
            app.random_coords = [];
            for x = randi([0, chunk_size - 1], 1, ceil(xsize / chunk_size)) + (1:chunk_size:xsize)
                for y = randi([0, chunk_size - 1], 1, ceil(ysize / chunk_size)) + (1:chunk_size:ysize)
                    app.random_coords(end + 1, :) = [x, y];
                end
            end

            app.Xcoordinate.Value = app.random_coords(app.current_random_index, 1);
            app.Ycoordinate.Value = app.random_coords(app.current_random_index, 2);
            replot_crosshair(app, app.Xcoordinate.Value, app.Ycoordinate.Value);
            plot_signal(app);
        end

        % Button pushed function: RightButton
        function RightButtonPushed(app, event)
            app.current_random_index = app.current_random_index + 1;
            if app.current_random_index > length(app.random_coords)
                app.current_random_index = 1;
            end
            app.Xcoordinate.Value = app.random_coords(app.current_random_index, 1);
            app.Ycoordinate.Value = app.random_coords(app.current_random_index, 2);
            replot_crosshair(app, app.Xcoordinate.Value, app.Ycoordinate.Value);
            plot_signal(app);
        end

        % Button pushed function: PerformPeakFindingButton
        function PerformPeakFindingButtonPushed(app, event)
            global gWide parameters_dataframe
            % profile on;
            data= gWide.signal;
            xsize = size(data, 2);
            ysize = size(data, 1);
            app.PerformPeakFindingButton.Enable = 'off';
            app.ParameterDropDown.Enable = 'off';
            app.ParameterDropDownLabel.Enable = 'off';
            drawnow

            params = get_params(app);
            num_params = numel(app.parameter_labels) - 1;
            [pdf, app.lock_laplacian, peak_info_df] = ...
                full_peak_wrapper([], gWide.signal, gWide.SweepParam, ...
                    params, app.lock_laplacian, params.shift * ones(ysize, xsize), ...
                    num_params);
            if ~isempty(app.roi_object)
                curr_param = app.all_parameter_dfs{app.LoadedListBox.ValueIndex};
                cmp_params = zeros(num_params, 1);
                pdf_wo_lapl = pdf(:, :, 1:16);
                mask = app.roi_mask;
                parfor x=1:xsize
                    for y=1:ysize
                       if(~mask(y, x))
                           % if pdf_wo_lapl(y, x, :) == cmp_params
                                pdf(y, x, :) = curr_param(y, x, :);
                           % end
                       end
                    end
                end
            end
            app.LaplacianThresholdLabel.Text = ['Current Laplacian Thresholds (MHz/pixel): ' sprintf('%g ', app.lock_laplacian)];
            % mpiprofile viewer;
            app.PerformPeakFindingButton.Enable = 'on';
            app.ParameterDropDown.Enable = 'on';
            app.ParameterDropDownLabel.Enable = 'on';
            drawnow

            i = app.ParameterDropDown.ValueIndex;
            if i > length(app.parameter_labels)
                i = 1;
                app.ParameterDropDown.ValueIndex = 1;
            end
            parameters_dataframe = pdf;
            
            app.all_peak_info_dfs{app.LoadedListBox.ValueIndex} = peak_info_df;
            app.all_reflection_dfs{app.LoadedListBox.ValueIndex} = params.shift * ones(ysize, xsize);
            app.all_parameter_dfs{app.LoadedListBox.ValueIndex} = parameters_dataframe;
            app.all_settings(app.LoadedListBox.ValueIndex) = params;

            plot_parameter(app, i, parameters_dataframe, app.DisplayAxes)
            plot_parameter(app, i, parameters_dataframe, app.ScriptDisplayAxes)
            surface3d(app)
            spectrum(app)
            replot_crosshair(app, app.Xcoordinate.Value, app.Ycoordinate.Value);
            plot_signal(app)
            align_image_setup(app);
        end

        % Button pushed function: LeftButton
        function LeftButtonPushed(app, event)
            app.current_random_index = app.current_random_index - 1;
            if app.current_random_index < 1
                app.current_random_index = length(app.random_coords);
            end
            app.Xcoordinate.Value = app.random_coords(app.current_random_index, 1);
            app.Ycoordinate.Value = app.random_coords(app.current_random_index, 2);
            replot_crosshair(app, app.Xcoordinate.Value, app.Ycoordinate.Value);
            plot_signal(app);
        end

        % Value changed function: ParameterDropDown
        function ParameterDropDownValueChanged(app, event)
            global parameters_dataframe
            i = app.ParameterDropDown.ValueIndex;
  
            if i > length(app.parameter_labels)
                plot_data(app)
                return;
            end

            plot_parameter(app, i, parameters_dataframe, app.DisplayAxes);
            plot_parameter(app, i, parameters_dataframe, app.ScriptDisplayAxes);
            func_idx = app.AvailableScriptsListBox.ValueIndex;
            if ~isempty(func_idx)
                if ~isempty(app.all_script_results{func_idx})
                    result = app.all_script_results{func_idx}.parameters;
                    plot_parameter(app, i, result, app.ScriptOutputAxes);
                end
            end
            if strcmp(app.TabGroup.SelectedTab.Title, "Slope Suppression")
                CalculateSlopesButtonPushed(app, event);
            end

            surface3d(app);
            replot_crosshair(app, app.Xcoordinate.Value, app.Ycoordinate.Value);
            plot_signal(app)
        end

        % Value changed function: LoadedListBox
        function LoadedListBoxValueChanged(app, event)
            global gWide parameters_dataframe
            index = app.LoadedListBox.ValueIndex;
            gWide = app.all_gWides(index);
            
            if length(app.all_parameter_dfs) >= index
                if isempty(app.all_parameter_dfs{index})
                    parameters_dataframe = [];
                    plot_data(app)
                    plot_signal(app)
                    app.PerformPeakFindingButton.Enable = 'on';
                    app.ParameterDropDown.Enable = 'off';
                    app.ParameterDropDownLabel.Enable = 'off';
                    if app.TabGroup.SelectedTab ~= app.GlobalActionsTab
                        set_params(app, app.all_settings(index), index);
                    end
                    return;
                end
                parameters_dataframe = app.all_parameter_dfs{index};
                app.PerformPeakFindingButton.Enable = 'on';
                app.ParameterDropDown.Enable = 'on';
                app.ParameterDropDownLabel.Enable = 'on';
                if app.TabGroup.SelectedTab ~= app.GlobalActionsTab
                    set_params(app, app.all_settings(index), index);
                end
            else
                parameters_dataframe = [];
                plot_data(app)
                plot_signal(app)
                app.PerformPeakFindingButton.Enable = 'on';
                app.ParameterDropDown.Enable = 'off';
                app.ParameterDropDownLabel.Enable = 'off';
                if app.TabGroup.SelectedTab ~= app.GlobalActionsTab
                    set_params(app, app.all_settings(index), index);
                end
                return;
            end
            
            i = app.ParameterDropDown.ValueIndex;

            if i > length(app.parameter_labels)
                plot_data(app)
                plot_signal(app)
                return;
            end

            plot_parameter(app, i, parameters_dataframe, app.DisplayAxes)
            plot_parameter(app, i, parameters_dataframe, app.ScriptDisplayAxes)

            title(app.DisplayAxes, [app.parameter_labels(i)]); % Add a title to each subplot
            hold(app.DisplayAxes, 'off')
            plot_signal(app)
            replot_crosshair(app, app.Xcoordinate.Value, app.Ycoordinate.Value)
        end

        % Value changed function: ShiftingEditField
        function ShiftingEditFieldValueChanged(app, event)
            plot_signal(app)
        end

        % Value changed function: ToleranceEditField
        function ToleranceEditFieldValueChanged(app, event)
            plot_signal(app)
        end

        % Value changed function: LowerLimitEditField
        function LowerLimitEditFieldValueChanged(app, event)
            if strcmp(app.TabGroup.SelectedTab.Title, "Slope Suppression")
                value = app.LowerLimitEditField.Value;
                value2 = app.UpperLimitEditField.Value;
                if value > value2
                    return
                end
                clim(app.SlopeMapAxes, [value, value2]);
            end
            
            value = app.LowerLimitEditField.Value;
            value2 = app.UpperLimitEditField.Value;
            if value > value2
                return
            end
            clim(app.DisplayAxes, [value, value2]);
            clim(app.ScriptDisplayAxes, [value, value2]);
        end

        % Value changed function: UpperLimitEditField
        function UpperLimitEditFieldValueChanged(app, event)
            if strcmp(app.TabGroup.SelectedTab.Title, "Slope Suppression")
                value = app.LowerLimitEditField.Value;
                value2 = app.UpperLimitEditField.Value;
                if value > value2
                    return
                end
                clim(app.SlopeMapAxes, [value, value2]);
            end

            value = app.LowerLimitEditField.Value;
            value2 = app.UpperLimitEditField.Value;
            if value > value2
                return
            end
            clim(app.DisplayAxes,[value, value2]);
            clim(app.ScriptDisplayAxes,[value, value2]);
        end

        % Value changed function: SmthDegreeSpinner
        function SmthDegreeSpinnerValueChanged(app, event)
            plot_signal(app);
        end

        % Value changed function: SmthSpanSpinner
        function SmthSpanSpinnerValueChanged(app, event)
            plot_signal(app);            
        end

        % Value changed function: OfPeaksSpinner
        function OfPeaksSpinnerValueChanged(app, event)
            plot_signal(app);
        end

        % Value changed function: ThresholdEditField
        function ThresholdEditFieldValueChanged(app, event)
            plot_signal(app);
        end

        % Value changed function: MinDistEditField
        function MinDistEditFieldValueChanged(app, event)
            plot_signal(app);
        end

        % Button down function: DisplayAxes
        function handle(app, event)
            point = app.DisplayAxes.CurrentPoint;
            if all(point(1, :) < 1)
                return;
            end
            app.Xcoordinate.Value = point(1, 1);
            app.Ycoordinate.Value = point(1, 2);
            replot_crosshair(app, point(1, 1), point(1, 2));
            plot_signal(app);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            global gWide parameters_dataframe
            if isempty(app.all_gWides) % nothing plotted
                uialert(app.UIFigure, "No figure plotted", "Error");
                return;
            end
            [file, location] = uiputfile('data.mat');
            if location ~= 0
                index = app.ParameterDropDown.ValueIndex;
                file_path = fullfile(location, file); 
                if index > numel(app.parameter_labels)
                    data = squeeze(gWide.signal(:,:,2))';
                    % file_path = fullfile(selected_folder, "data.mat");
                    save(file_path, 'data');
                else
                    if isempty(parameters_dataframe) || isempty(app.all_parameter_dfs)
                        uialert(app.UIFigure, "Error getting parameters, have you performed peak finding?", "Error");                        
                    else
                        uialert(app.UIFigure, "File successfully saved to directory.", ...
                            "Success", 'Icon', 'success');
                        data = squeeze(parameters_dataframe(:, :, index));
                        % file_path = fullfile(selected_folder, "data.mat");
                        save(file_path, 'data');
                    end
                end
            end
            figure(app.UIFigure)
        end

        % Button down function: SlopeMapAxes
        function SlopeMapAxesButtonDown(app, event)
            point = app.SlopeMapAxes.CurrentPoint;
            app.Xcoordinate.Value = point(1, 1);
            app.Ycoordinate.Value = point(1, 2);
            replot_crosshair(app, point(1, 1), point(1, 2));
            plot_signal(app);
        end

        % Cell edit callback: SlopeFileDataTable
        function SlopeFileDataTableCellEdit(app, event)
            % indices = event.Indices;
            % newData = event.NewData;
            plot_signal(app); 
        end

        % Button pushed function: CalculateSlopesButton
        function CalculateSlopesButtonPushed(app, event)
            items = app.FilestoUseListBox.Value;
            if isempty(items)
                uialert(app.UIFigure, "Please choose which files you want to calculate the slope between.", "Error")
                return;
            end
            parameters_idx = ismember(app.LoadedListBox.Items, items);
            all_data = app.all_parameter_dfs(parameters_idx);
            
            ref = app.LoadedListBox.Value;
            if ~ismember(items, ref)
                uialert(app.UIFigure, "Using first item in selection as zero field splitting index, " + ...
                    "if you'd like to change this please select one of the loaded items in the " + ...
                    "list of loaded files on the left panel.", "Warning", "Icon", "warning");
                ref_index = 1;
            else
                ref_index = ismember(items, ref);
                items = items(~ref_index);
            end

            zfs_vals = all_data{ref_index};
            
            index = app.ParameterDropDown.ValueIndex;
            if index > numel(app.parameter_labels)
                index = 1;
            end
            matching_value = app.ParameterDropDown.Value;
            msg = "Calculating slopes for " + items + " with ZFS " ...
                +  app.FilestoUseListBox.Value{ref_index} + " on parameter " + matching_value + newline;
            selection = uiconfirm(app.UIFigure, msg, "Confirm Slope Calculation");
            if strcmp(selection, 'Cancel')
                return;
            end
            
            test = app.all_gWides(1).signal;
            xsize = size(test, 1);
            ysize = size(test, 2);
            all_params = zeros(xsize, ysize, numel(all_data));
            for i=1:numel(all_data)
                curr_param = all_data{i};
                all_params(:, :, i) = curr_param(:, :, index);
            end

            app.slope_df = zeros(xsize, ysize, 3);
            f = waitbar(0, "Starting slopes calculation");
            for x = 1:xsize
                waitbar(x / xsize, f, "Calculating for column " + x);
                for y = 1:ysize
                    y_vals = squeeze(all_params(x, y, :));
                    % zfs = zfs_vals(y, x, 2);
                    % if isnan(zfs)
                    %     zfs = 10;
                    % end
                    y_vals(isnan(y_vals)) = 10;
                    [slope, r_squared, intercept] = calculate_slopes(y_vals, app.x_coords(parameters_idx), zfs_vals(x, y, index));
                    app.slope_df(x, y, :) = [slope, r_squared, intercept];
                end
            end
            waitbar(1, f, "Completed!");
            close(f);

            plot_index = app.PlottedValueDropDown.ValueIndex;
            data = app.slope_df(:, :, plot_index);
            if plot_index == 1 && app.DifferencefromIdealSwitch.ValueIndex == 2
                ideal_slope = 28.025 * 1e5 * 24.11;
                data = data ./ ideal_slope;
            end
            if plot_index == 2
                colormap(app.SlopeMapAxes, 'jet');
            else
                colormap(app.SlopeMapAxes, 'default');
            end
            data(data == 0) = NaN;
            cla(app.SlopeMapAxes)
            set(app.SlopeMapAxes,'color', [1 0 0]);
            im = imagesc(app.SlopeMapAxes, data, 'AlphaData', ~isnan(data)); % Display the slice
            im.HitTest = 'off';
            colorbar(app.SlopeMapAxes); % Add a colorbar to each subplot
            clim(app.SlopeMapAxes, "auto");
            lims = clim(app.SlopeMapAxes);
            app.LowerLimitEditField.Value = lims(1);
            app.UpperLimitEditField.Value = lims(2);
            xlim(app.SlopeMapAxes,[1 size(data,2)])
            ylim(app.SlopeMapAxes,[1 size(data,1)])
            pbaspect(app.SlopeMapAxes,[1 size(data,1)/size(data,2) 1]);
            hold(app.SlopeMapAxes, 'on');
            title(app.SlopeMapAxes, [app.parameter_labels(index), " ", app.PlottedValueDropDown.Value]); % Add a title to each subplot
            hold(app.SlopeMapAxes, 'off')

            plot_signal(app);
        end

        % Value changed function: PlottedValueDropDown
        function PlottedValueDropDownValueChanged(app, event)
            plot_index = app.PlottedValueDropDown.ValueIndex;
            data = app.slope_df(:, :, plot_index);
            if plot_index == 1 && app.DifferencefromIdealSwitch.ValueIndex == 2
                ideal_slope = 28.025 * 1e5 * 24.11;
                data = data ./ ideal_slope;
            end
            data(data == 0) = NaN;
            cla(app.SlopeMapAxes)
            set(app.SlopeMapAxes,'color', [1 0 0]);
            im = imagesc(app.SlopeMapAxes, data, 'AlphaData', ~isnan(data)); % Display the slice
            im.HitTest = 'off';
            if plot_index == 2
                data = 1 - data;
                colormap(app.SlopeMapAxes, 'jet');
            else
                colormap(app.SlopeMapAxes, 'default');
            end
            colorbar(app.SlopeMapAxes); % Add a colorbar to each subplot
            clim(app.SlopeMapAxes, "auto");
            lims = clim(app.SlopeMapAxes);
            app.LowerLimitEditField.Value = lims(1);
            app.UpperLimitEditField.Value = lims(2);
            xlim(app.SlopeMapAxes,[1 size(data,2)])
            ylim(app.SlopeMapAxes,[1 size(data,1)])
            pbaspect(app.SlopeMapAxes,[1 size(data,1)/size(data,2) 1]);
            hold(app.SlopeMapAxes, 'on');
            i = app.ParameterDropDown.ValueIndex;
            title(app.SlopeMapAxes, [app.parameter_labels(i), " ", app.PlottedValueDropDown.Value]); % Add a title to each subplot
            hold(app.SlopeMapAxes, 'off')
        end

        % Value changed function: DifferencefromIdealSwitch
        function DifferencefromIdealSwitchValueChanged(app, event)
            PlottedValueDropDownValueChanged(app, event);
        end

        % Value changed function: Xcoordinate
        function XcoordinateValueChanged2(app, event)
            replot_crosshair(app, app.Xcoordinate.Value, app.Ycoordinate.Value);
            plot_signal(app);
        end

        % Value changing function: Xcoordinate
        function XcoordinateValueChanging(app, event)
            replot_crosshair(app, event.Value, app.Ycoordinate.Value);
            plot_signal(app, event.Value, app.Ycoordinate.Value);
        end

        % Value changed function: Ycoordinate
        function YcoordinateValueChanged2(app, event)
            replot_crosshair(app, app.Xcoordinate.Value, app.Ycoordinate.Value);
            plot_signal(app)
        end

        % Value changing function: Ycoordinate
        function YcoordinateValueChanging(app, event)
            replot_crosshair(app, app.Xcoordinate.Value, event.Value);
            plot_signal(app, app.Xcoordinate.Value, event.Value)
        end

        % Value changed function: GeneratingDropDown
        function GeneratingDropDownValueChanged(app, event)
            plot_signal(app);
        end

        % Button pushed function: AlignImagesButton
        function AlignImagesButtonPushed(app, event)
            items = app.FilestoUseListBox.Value;
            if isempty(items)
                uialert(app.UIFigure, "Please select images to align.", "Error")
                return;
            end
            parameters_idx = ismember(app.LoadedListBox.Items, items);
            all_data = app.all_parameter_dfs(parameters_idx);
            fluorescence = app.all_gWides(parameters_idx);
            
            ref = app.LoadedListBox.Value;
            if ~ismember(items, ref)
                uialert(app.UIFigure, "Using last item in selection as reference index, " + ...
                    "if you'd like to change this please select one of the loaded items in the " + ...
                    "list of loaded files on the left panel.", "Warning", "Icon", "warning");
                ref_index = numel(items);
            else
                ref_index = ismember(items, ref);
                items = items(~ref_index);
            end

            matching_index = app.ParameterDropDown.ValueIndex;
            matching_value = app.ParameterDropDown.Value;
            msg = "Aligning image " + items + " with " ...
                +  app.FilestoUseListBox.Value{ref_index} + " on parameter " + matching_value + newline;
            selection = uiconfirm(app.UIFigure, msg, "Confirming Alignment");
            if strcmp(selection, 'Cancel')
                return;
            end

            using_fluorescence = false;
            if matching_index > numel(app.parameter_labels)
                fixed_data = fluorescence(ref_index).signal(:, :, matching_index)';
                using_fluorescence = true;
            else
                fixed_data = all_data{ref_index};
                fixed_data = fixed_data(:, :, matching_index);
            end

            for i=1:numel(all_data)
                if using_fluorescence
                    moving_data = fluorescence(i).signal(:, :, matching_index)';
                else
                    moving_data = all_data{i};
                    moving_data = moving_data(:, :, matching_index);
                end
                [tform, refframe, success] = align_images(fixed_data, moving_data, strcmp(app.DisplayAlignmentSwitch.Value, 'On'));
                if ~success
                    uialert(app.UIFigure, "Failure to match identifying features for image " + ...
                        app.FilestoUseListBox.Value{i}, "Error");
                end
                % Transform all data associated with the moving data

                listbox_val = app.FilestoUseListBox.Value{i};
                actual_idx = ismember(app.LoadedListBox.Items, listbox_val);
                actual_idx = find(actual_idx == 1);
                for j=1:numel(app.parameter_labels)
                    data = app.all_parameter_dfs{actual_idx};
                    transformed_data = transform_data(data(:, :, j), tform, refframe);
                    app.all_parameter_dfs{actual_idx}(:, :, j) = transformed_data;
                end
            end
        end

        % Button pushed function: SavePlotDataButton
        function SavePlotDataButtonPushed(app, event)
            if isempty(app.slope_df) % nothing plotted
                uialert(app.UIFigure, "No figure plotted", "Error");
                return;
            end
            [file, location] = uiputfile('data.mat');
            if location ~= 0
                file_path = fullfile(location, file); 
                uialert(app.UIFigure, "File successfully saved to directory.", ...
                    "Success", 'Icon', 'success');
                data = squeeze(app.slope_df(:, :, 1));
                save(file_path, 'data');
                figure;
                imagesc(data)
            end
            figure(app.UIFigure)
        end

        % Button pushed function: PassDownSplitButton
        function PassDownSplitButtonPushed(app, event)
            global parameters_dataframe
            items = app.FilestoUseListBox.Value;
            if isempty(items)
                uialert(app.UIFigure, "Please select maps to repass.", "Error")
                return;
            end
            self_pass = isscalar(items);
            parameters_idx = ismember(app.LoadedListBox.Items, items);
            all_data = app.all_parameter_dfs(parameters_idx);
            fluorescence = app.all_gWides(parameters_idx);
            
            ref = app.LoadedListBox.Value;
            if ~ismember(items, ref)
                uialert(app.UIFigure, "Using last item in selection as reference index, " + ...
                    "if you'd like to change this please select one of the loaded items in the " + ...
                    "list of loaded files on the left panel.", "Warning", "Icon", "warning");
                ref_index = numel(items);
            else
                ref_index = ismember(items, ref);
                if ~self_pass
                    items = items(~ref_index);
                end
            end
            params = get_params(app);

            % matching_index = app.ParameterDropDown.ValueIndex;
            % matching_value = app.ParameterDropDown.Value;
            msg = "Rerunning " + items;
            if params.partnering_method == 1
                msg = msg + " with the D map of ";
            elseif params.partnering_method == 2
                msg = msg + " with the E map of ";
            else
                msg = msg + " with the D map of ";
            end
            msg = msg +  app.FilestoUseListBox.Value{ref_index} + " on all parameters." + newline;
            selection = uiconfirm(app.UIFigure, msg, "Confirming Rerun");
            if strcmp(selection, 'Cancel')
                return;
            end

            ref_data = all_data{ref_index};
            if params.partnering_method == 1
                ref_data = ref_data(:, :, 1); % D_111 map
            elseif params.partnering_method == 2
                ref_data = ref_data(:, :, 2); % E_111 map
            else
                ref_data = ref_data(:, :, 1); % Naive: D_111 map
            end
            ref_data = ref_data + app.OffsetEditField.Value; % Offset
            
            ref_index = find(ref_index == 1);
            for i = 1:numel(all_data)
                if i == ref_index && ~self_pass % Skip the one that we got the D_111 map from
                    continue;
                end
                gWide = fluorescence(i);
                app.PerformPeakFindingButton.Enable = 'off';
                app.ParameterDropDown.Enable = 'off';
                app.ParameterDropDownLabel.Enable = 'off';
                drawnow

                [parameters_dataframe, app.lock_laplacian, peak_info_df] = ...
                    full_peak_wrapper([], ...
                        gWide.signal, gWide.SweepParam, ...
                        params, app.lock_laplacian, ...
                        ref_data, numel(app.parameter_labels) - 1);
                app.LaplacianThresholdLabel.Text = ['Current Laplacian Thresholds (MHz/pixel): ' sprintf('%g ', app.lock_laplacian)];

                listbox_val = app.FilestoUseListBox.Value{i};
                actual_idx = ismember(app.LoadedListBox.Items, listbox_val);
                actual_idx = find(actual_idx == 1);

                app.all_parameter_dfs{actual_idx} = parameters_dataframe;
                app.all_settings(actual_idx) = params;
                app.all_reflection_dfs{actual_idx} = ref_data;
                app.all_peak_info_dfs{actual_idx} = peak_info_df;
                app.PerformPeakFindingButton.Enable = 'on';
                app.ParameterDropDown.Enable = 'on';
                app.ParameterDropDownLabel.Enable = 'on';
                drawnow

                surface3d(app)
                spectrum(app)
    
                plot_signal(app)
                align_image_setup(app);

                j = app.ParameterDropDown.ValueIndex;
                if j > length(app.parameter_labels)
                    j = 1;
                    app.ParameterDropDown.ValueIndex = 1;
                end
                plot_parameter(app, j, parameters_dataframe, app.DisplayAxes)
                plot_parameter(app, i, parameters_dataframe, app.ScriptDisplayAxes)
            end
        end

        % Value changed function: HighlightErrorsButton
        function HighlightErrorsButtonValueChanged(app, event)
            % value = app.HighlightErrorsButton.Value;
            ParameterDropDownValueChanged(app, event);
        end

        % Value changed function: SeedingDropDown
        function SeedingDropDownValueChanged(app, event)
            plot_signal(app)
        end

        % Value changed function: LockLaplacianButton
        function LockLaplacianButtonValueChanged(app, event)
            % Recalculating with standard thresholds requires a complete
            % refind in order to allow for laplacian repairs
            % Thus, locking/unlocking shouldn't change anything about the
            % actual display itself but rather allow one to refit
        end

        % Value changed function: LineCutDropDown
        function LineCutDropDownValueChanged(app, event)
            plot_signal(app)
        end

        % Value changed function: NormalizeCountsButton
        function NormalizeCountsButtonValueChanged(app, event)
            plot_signal(app);
            app.ParameterDropDownValueChanged(app);
        end

        % Value changed function: PreviousSettingsButton
        function PreviousSettingsButtonValueChanged(app, event)
            % value = app.PreviousSettingsButton.Value;
            plot_signal(app);
            replot_crosshair(app, app.Xcoordinate.Value, app.Ycoordinate.Value);
        end

        % Value changed function: PartneringDropDown
        function PartneringDropDownValueChanged(app, event)
            value = app.PartneringDropDown.ValueIndex;
            if value == 2
                app.ShiftingEditFieldLabel.Enable = "on";
                app.ShiftingEditField.Enable = "on";
                app.ShiftingEditFieldLabel.Text = "Splitting";
                % app.MaxShiftingEditFieldLabel.Text = "Max Splitting";
            elseif value == 1
                app.ShiftingEditFieldLabel.Enable = "on";
                app.ShiftingEditField.Enable = "on";
                app.ShiftingEditFieldLabel.Text = "Shifting";
                % app.MaxShiftingEditFieldLabel.Text = "Max Shifting";
            elseif value == 3
                app.ShiftingEditFieldLabel.Enable = "off";
                app.ShiftingEditField.Enable = "off";
            end

            plot_signal(app)
        end

        % Button pushed function: SavetoBaseWorkspaceButton
        function SavetoBaseWorkspaceButtonPushed(app, event)
            global gWide parameters_dataframe
            if isempty(app.all_gWides) % nothing plotted
                uialert(app.UIFigure, "No figure plotted", "Error");
                return;
            end
            if isempty(parameters_dataframe) || isempty(app.all_parameter_dfs)
                uialert(app.UIFigure, "Error getting parameters, have you performed peak finding?", "Error");
                return
            end
            curr_file = app.LoadedListBox.Value;
            selection = uiconfirm(app.UIFigure, "Saving file " + curr_file, "Save", ...
                "Options", ["Save all parameters", "Save this parameter", "Cancel"], ...
                "CancelOption", 3);
            switch selection
                case "Save all parameters"
                    all_param_data = struct();
                    all_param_data.gWide = gWide;
                    all_param_data.parameters_dataframe = parameters_dataframe;
                    params = get_params(app);
                    all_param_data.params = params;
                    all_param_data.reflection_df = app.all_reflection_dfs{app.LoadedListBox.ValueIndex};
                    all_param_data.peak_info_df = app.all_peak_info_dfs{app.LoadedListBox.ValueIndex};
                    all_param_data.param_labels = app.parameter_labels;
                    assignin("base", "all_param_data", all_param_data);
                case "Save this parameter"
                    index = app.ParameterDropDown.ValueIndex;
                    if index > numel(app.parameter_labels)
                        data = squeeze(gWide.signal(:,:,2))';
                        assignin("base", "param_data", data)
                    else
                        if isempty(parameters_dataframe) || isempty(app.all_parameter_dfs)
                            uialert(app.UIFigure, "Error getting parameters, have you performed peak finding?", "Error");                        
                        else
                            uialert(app.UIFigure, "File successfully saved to directory.", ...
                                "Success", 'Icon', 'success');
                            data = squeeze(parameters_dataframe(:, :, index));
                            assignin("base", "param_data", data)
                        end
                    end
                otherwise
                    return
            end
        end

        % Cell edit callback: ParametersTable
        function ParametersTableCellEdit(app, event)
            indices = event.Indices;
            newValue = event.NewData;
            newData = str2double(newValue);
            if isnan(newData)
                uialert(app.UIFigure, 'Please enter a numeric value.', 'Invalid Input');
                app.ParametersTable.Data(indices(1), indices(2)) = event.PreviousData;
            end
        end

        % Button pushed function: SaveAllButton
        function SaveAllButtonPushed(app, event)
            global gWide parameters_dataframe
            if isempty(app.all_gWides) % nothing plotted
                uialert(app.UIFigure, "No figure plotted", "Error");
                return;
            end
            if isempty(parameters_dataframe) || isempty(app.all_parameter_dfs)
                uialert(app.UIFigure, "Error getting parameters, have you performed peak finding?", "Error");
                return
            end
            all_param_data = struct();
            all_param_data.gWide = gWide;
            all_param_data.parameters_dataframe = parameters_dataframe;
            params = get_params(app);
            all_param_data.params = params;
            all_param_data.reflection_df = app.all_reflection_dfs{app.LoadedListBox.ValueIndex};
            all_param_data.param_labels = app.parameter_labels;
            [file, location] = uiputfile('data.mat');
            if location ~= 0
                file_path = fullfile(location, file);
                uialert(app.UIFigure, "File successfully saved to directory.", ...
                    "Success", 'Icon', 'success');
                % file_path = fullfile(selected_folder, "data.mat");
                save(file_path, 'all_param_data');
            end
            figure(app.UIFigure);
        end

        % Button pushed function: PreviewButton
        function PreviewButtonPushed(app, event)
            % Runs current function output, does not save
            f = waitbar(0, "Constructing parameters");
            idxs = app.InputFilesListBox.ValueIndex;
            params=get_params(app);
            
            in_signal = app.all_gWides(idxs).signal;

            xsize = size(in_signal, 1);
            ysize = size(in_signal, 2);
            app.all_reflection_dfs{idxs} = params.shift * ones(ysize, xsize);


            in_sweep_param = app.all_gWides(idxs).SweepParam;
            in_peak_info_df = app.all_peak_info_dfs(idxs);
            in_parameter_df = app.all_parameter_dfs(idxs);
            in_reflection_df = app.all_reflection_dfs(idxs);
            %app.all_settings(idxs)=params;
            in_settings_df = app.all_settings(idxs);
            in_new_settings_df = get_params(app);

            input_struct = struct("signal", in_signal, ...
                "SweepParam", in_sweep_param, "parameters", in_parameter_df, ...
                "peak_info", in_peak_info_df, "reflection", in_reflection_df, ...
                "settings", in_settings_df, "new_settings", in_new_settings_df);
            assignin("base", "function_inputs", input_struct);
            
            waitbar(0.25, f, "Selecting Script")
            func_idx = app.AvailableScriptsListBox.ValueIndex;
            if(isempty(func_idx))
                uialert(app.UIFigure, "No script selected", "Error");
                return;
            end
            scriptPath = app.user_script_paths{func_idx};
            [script_folder, file] = fileparts(scriptPath);
            waitbar(0.5, f, "Running Script");
            if ~contains(path, script_folder)
                addpath(script_folder);
            end
            if(iscellstr(file)); file=file{1}; end
            funcHandle = str2func(file);
            try
                result = funcHandle(input_struct);
            catch ME
                disp(ME.message)
                for i = 1:numel(ME.stack)
                    disp(ME.stack(i))
                end
                uialert(app.UIFigure, sprintf('Error running script on line %d:\n%s', ME.stack(1).line, ME.message), 'Execution Error');
                close(f);
                return;
            end
            waitbar(0.75, f, "Plotting Outputs");
            app.all_script_results{func_idx} = result;
            assignin("base", "results", app.all_script_results)
            plot_parameter(app,app.ParameterDropDown.ValueIndex, result(1).parameters, app.ScriptOutputAxes);
            waitbar(1, f, "Completed Script!");            
            pause(0.5);
            close(f);
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            % Runs current function output and saves
            f = waitbar(0, "Constructing Parameters");
            params=get_params(app);
            idxs = app.InputFilesListBox.ValueIndex;
            in_signal = app.all_gWides(idxs).signal;
            xsize = size(in_signal, 1);
            ysize = size(in_signal, 2);
            app.all_reflection_dfs{idxs} = params.shift * ones(ysize, xsize);
            in_sweep_param = app.all_gWides(idxs).SweepParam;
            in_peak_info_df = app.all_peak_info_dfs(idxs);
            in_parameter_df = app.all_parameter_dfs(idxs);
            in_reflection_df = app.all_reflection_dfs(idxs);
            in_settings_df = app.all_settings(idxs);
            
            in_new_settings_df = get_params(app);
            input_struct = struct("signal", in_signal, ...
                "SweepParam", in_sweep_param, "parameters", in_parameter_df, ...
                "peak_info", in_peak_info_df, "reflection", in_reflection_df, ...
                "settings", in_settings_df, "new_settings", in_new_settings_df);
            assignin("base", "function_inputs", input_struct);

            waitbar(0.25, f, "Selecting Script")
            func_idx = app.AvailableScriptsListBox.ValueIndex;
            if(isempty(func_idx))
                uialert(app.UIFigure, "No script selected", "Error");
                return;
            end
            scriptPath = app.user_script_paths{func_idx};
            [script_folder, file] = fileparts(scriptPath);
            waitbar(0.5, f, "Running Script");
            if ~contains(path, script_folder)
                addpath(script_folder);
            end
            if(iscellstr(file)); file=file{1}; end
            funcHandle = str2func(file);
            try
                result = funcHandle(input_struct);
            catch ME
                uialert(app.UIFigure, sprintf('Error running script:\n%s', ME.message), 'Execution Error');    
            end

            waitbar(0.75, f, "Plotting Outputs");
            app.all_script_results{func_idx} = result;
            assignin("base", "results", app.all_script_results)
            plot_parameter(app,app.ParameterDropDown.ValueIndex, result(1).parameters, app.ScriptOutputAxes);

            waitbar(0.9, f, "Saving to File");
            out_idxs = app.OutputFilesListBox.ValueIndex;
            for i = 1:numel(out_idxs)
                out_idx = out_idxs(i);
                app.all_gWides(out_idx).signal = result.signal;
                app.all_gWides(out_idx).SweepParam = result.SweepParam;
                app.all_peak_info_dfs{out_idx} = result.peak_info;
                app.all_parameter_dfs{out_idx} = result.parameters;
                app.all_reflection_dfs{out_idx} = result.reflection;
                app.all_settings(out_idx) = result(i).settings(i);
                if isfield(result, 'gpu')
                    app.all_gWides(out_idx).gpu = result.gpu;
                end
            end
            app.LoadedListBoxValueChanged(app);
            set_params(app, result.new_settings, app.LoadedListBox.ValueIndex);
            waitbar(1, f, "Completed Script!");            
            pause(0.5);
            close(f);
        end

        % Value changed function: ZoningEditField
        function ZoningEditFieldValueChanged(app, event)
            plot_signal(app);
        end

        % Value changed function: ZoningDropDown
        function ZoningDropDownValueChanged(app, event)
            plot_signal(app);
        end

        % Button down function: ScriptDisplayAxes
        function handle_script(app, event)
            point = app.ScriptDisplayAxes.CurrentPoint;
            if all(point(1, :) < 1)
                return;
            end
            app.Xcoordinate.Value = point(1, 1);
            app.Ycoordinate.Value = point(1, 2);
            replot_crosshair(app, point(1, 1), point(1, 2));
            plot_signal(app);
        end

        % Button pushed function: UploadButton
        function UploadButtonPushed(app, event)
            % Script uploaded to app
            [files, path] = uigetfile('*.m', 'Select Scripts', 'MultiSelect', 'on');
            figure(app.UIFigure);
            if isequal(files,0); return; end
            if(~iscellstr(files)); files = {files}; end
            for i=1:length(files)
                app.user_script_paths{end+1} = fullfile(path, files(i));
               app.AvailableScriptsListBox.Items(end+1) = files(i);
               app.all_script_results{end+1} = zeros(0,1);
            end
        end

        % Value changed function: OutputFilesListBox
        function OutputFilesListBoxValueChanged(app, event)
            idxs = app.OutputFilesListBox.ValueIndex;
            if(isempty(app.AvailableScriptsListBox.ValueIndex))
                return;
            end
            if isempty(app.all_script_results{app.AvailableScriptsListBox.ValueIndex})
                return;
            end
            result = app.all_script_results{app.AvailableScriptsListBox.ValueIndex}.parameters;
            if(size(result, 3) > 1)
                result = result(idxs(1));
            end
            plot_parameter(app, app.ParameterDropDown.ValueIndex, result, app.ScriptOutputAxes);
        end

        % Button down function: ScriptOutputAxes
        function handle_output(app, event)
            point = app.ScriptOutputAxes.CurrentPoint;
            if all(point(1, :) < 1)
                return;
            end
            app.Xcoordinate.Value = point(1, 1);
            app.Ycoordinate.Value = point(1, 2);
            replot_crosshair(app, point(1, 1), point(1, 2));
            plot_signal(app);
        end

        % Button pushed function: DefineROIButton
        function DefineROIButtonPushed(app, event)
            % When an ROI is set, all scripts or peak finds on the ROI only
            % work on the region.
            if ~isempty(app.roi_object) && isvalid(app.roi_object)
                delete(app.roi_object)
                app.roi_object = [];
                app.roi_mask = [];
            end
            app.ShowROICheckBox.Value = true;
            if app.TabGroup.SelectedTab == app.ScriptRunsTab
                obj = drawpolygon(app.ScriptDisplayAxes, 'LineWidth', 2, 'Color', 'w');
                app.roi_object = drawpolygon(app.DisplayAxes, 'Position', obj.Position, 'LineWidth', 2, 'Color', 'w');
            else
                app.roi_object = drawpolygon(app.DisplayAxes, 'LineWidth', 2, 'Color', 'w');
                drawpolygon(app.ScriptDisplayAxes, 'Position', app.roi_object.Position, 'LineWidth', 2, 'Color', 'w');
            end
            updateROIMask(app);
        end

        % Value changed function: ShowROICheckBox
        function ShowROICheckBoxValueChanged(app, event)
            % Show current ROI
            if ~isempty(app.roi_object) && isvalid(app.roi_object)
                if app.ShowROICheckBox.Value
                    app.roi_object.Visible = 'on';
                else
                    app.roi_object.Visible = 'off';
                end
            end
        end

        % Button pushed function: ClearROIButton
        function ClearROIButtonPushed(app, event)
            delete(app.roi_object);
            app.roi_object = [];
            app.roi_mask = [];
            updateROIMask(app);
        end

        % Button pushed function: PlotROIButton
        function PlotROIButtonPushed(app, event)
            figure;
            imagesc(app.roi_mask);
            colorbar;
        end

        % Button pushed function: PreviewFindButton
        function PreviewFindButtonPushed(app, event)
            global gWide parameters_dataframe
            % profile on;
            data= gWide.signal;
            xsize = size(data, 2);
            ysize = size(data, 1);
            app.PerformPeakFindingButton.Enable = 'off';
            app.ParameterDropDown.Enable = 'off';
            app.ParameterDropDownLabel.Enable = 'off';
            drawnow

            params = get_params(app);
            num_params = numel(app.parameter_labels) - 1;
            [pdf, app.lock_laplacian, peak_info_df] = ...
                full_peak_wrapper([], gWide.signal, gWide.SweepParam, ...
                    params, app.lock_laplacian, params.shift * ones(ysize, xsize), ...
                    num_params);
            if ~isempty(app.roi_object)
                curr_param = app.all_parameter_dfs{app.LoadedListBox.ValueIndex};
                cmp_params = zeros(num_params, 1);
                pdf_wo_lapl = pdf(:, :, 1:16);
                mask = app.roi_mask;
                parfor x=1:xsize
                    for y=1:ysize
                       if(~mask(y, x))
                           % if pdf_wo_lapl(y, x, :) == cmp_params
                                pdf(y, x, :) = curr_param(y, x, :);
                           % end
                       end
                    end
                end
            end
            app.LaplacianThresholdLabel.Text = ['Current Laplacian Thresholds (MHz/pixel): ' sprintf('%g ', app.lock_laplacian)];
            % mpiprofile viewer;
            app.PerformPeakFindingButton.Enable = 'on';
            app.ParameterDropDown.Enable = 'on';
            app.ParameterDropDownLabel.Enable = 'on';
            drawnow

            i = app.ParameterDropDown.ValueIndex;
            if i > length(app.parameter_labels)
                i = 1;
                app.ParameterDropDown.ValueIndex = 1;
            end
            parameters_dataframe = pdf;

            preview = struct("peak_info_df", peak_info_df, ...
                "reflection", params.shift * ones(ysize, xsize), ...
                "parameters", parameters_dataframe, ...
                "settings", params);
            app.all_preview_dfs{app.LoadedListBox.ValueIndex} = preview;
            
            plot_parameter(app, i, parameters_dataframe, app.DisplayAxes)
            plot_parameter(app, i, parameters_dataframe, app.ScriptDisplayAxes)
            surface3d(app)
            spectrum(app)
            replot_crosshair(app, app.Xcoordinate.Value, app.Ycoordinate.Value);
            plot_signal(app)
            align_image_setup(app);
        end

        % Button pushed function: SavePreviewButton
        function SavePreviewButtonPushed(app, event)
            preview = app.all_preview_dfs{app.LoadedListBox.ValueIndex};
            if isempty(preview); uialert("No Preview Saved"); return; end

            app.all_peak_info_dfs{app.LoadedListBox.ValueIndex} = preview.peak_info_df;
            app.all_reflection_dfs{app.LoadedListBox.ValueIndex} = preview.reflection;
            app.all_parameter_dfs{app.LoadedListBox.ValueIndex} = preview.parameters;
            app.all_settings(app.LoadedListBox.ValueIndex) = preview.settings;
            surface3d(app)
            spectrum(app)
            replot_crosshair(app, app.Xcoordinate.Value, app.Ycoordinate.Value);
            plot_signal(app)
            align_image_setup(app);
        end

        % Value changed function: chunksCheckBox
        function chunksCheckBoxValueChanged(app, event)
            %value = app.chunksCheckBox.Value;
            global gWide
            sig=gWide.signal;
            mn = median(sig, 3);
    
            % Replicate mn along the third dimension to match the dimensions of signal
            mn_expanded = repmat(mn, 1, 1, size(sig, 3));
            
            % Replace signal(x, y, z) with 2 * mn - signal(x, y, z)
            flip_signal = 2 * mn_expanded - sig;
            gWide.signal=flip_signal;
            index = app.LoadedListBox.ValueIndex;
            app.all_gWides(index)=gWide;

            %plot_data(app);

            
            % value = app.ChunksCheckBox.Value;
            % if value

        end

        % Clicked callback: LoadedListBox
        function LoadedListBoxClicked(app, event)
            item = event.InteractionInformation.Item;
            
        end

        % Value changed function: Refine
        function RefineValueChanged(app, event)
            value = app.Refine.Value;
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {721, 721};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {219, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftPanel.Title = ',Peak Finding Debugger';
            app.GridLayout6 = uigridlayout(app.LeftPanel);
            app.ShowPeaksSwitchLabel = uilabel(app.GridLayout6);
            app.ShowPeaksSwitchLabel.Text = 'Show Peaks';
            app.LoadedListBox = uilistbox(app.GridLayout6);
            app.LoadedListBox.Items = {};
            app.LoadedListBox.ValueChangedFcn = createCallbackFcn(app, @LoadedListBoxValueChanged, true);
            app.LoadedListBox.ClickedFcn = createCallbackFcn(app, @LoadedListBoxClicked, true);
            app.LoadedListBox.Value = {};
            app.LoadDataButton = uibutton(app.GridLayout6, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.SaveButton = uibutton(app.GridLayout6, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.ShowRawRedLabel = uilabel(app.GridLayout6);
            app.ShowRawRedLabel.Text = 'Show Raw (Red)';
            app.ShowRawSwitch = uiswitch(app.GridLayout6, 'slider');
            app.ShowRawSwitch.ValueChangedFcn = createCallbackFcn(app, @ShowRawSwitchValueChanged, true);
            app.ShowPeaksSwitch = uiswitch(app.GridLayout6, 'slider');
            app.ShowPeaksSwitch.ValueChangedFcn = createCallbackFcn(app, @ShowPeaksSwitchValueChanged, true);
            app.ShowPeaksSwitch.Value = 'On';
            app.PerformPeakFindingButton = uibutton(app.GridLayout6, 'push');
            app.PerformPeakFindingButton.ButtonPushedFcn = createCallbackFcn(app, @PerformPeakFindingButtonPushed, true);
            app.PerformPeakFindingButton.Text = 'Perform Peak Finding';
            app.SaveAllButton = uibutton(app.GridLayout6, 'push');
            app.SaveAllButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAllButtonPushed, true);
            app.SaveAllButton.Text = 'Save All ';
            app.Xcoordinate = uispinner(app.GridLayout6);
            app.Xcoordinate.ValueChangingFcn = createCallbackFcn(app, @XcoordinateValueChanging, true);
            app.Xcoordinate.Limits = [1 512];
            app.Xcoordinate.RoundFractionalValues = 'on';
            app.Xcoordinate.ValueChangedFcn = createCallbackFcn(app, @XcoordinateValueChanged2, true);
            app.Xcoordinate.Value = 1;
            app.XcoordinateSpinnerLabel = uilabel(app.GridLayout6);
            app.XcoordinateSpinnerLabel.Text = 'X coordinate';
            app.Ycoordinate = uispinner(app.GridLayout6);
            app.Ycoordinate.ValueChangingFcn = createCallbackFcn(app, @YcoordinateValueChanging, true);
            app.Ycoordinate.Limits = [1 512];
            app.Ycoordinate.RoundFractionalValues = 'on';
            app.Ycoordinate.ValueChangedFcn = createCallbackFcn(app, @YcoordinateValueChanged2, true);
            app.Ycoordinate.Value = 1;
            app.YcoordinateLabel = uilabel(app.GridLayout6);
            app.YcoordinateLabel.Text = 'Y coordinate';
            app.ShiftingEditField = uieditfield(app.GridLayout6, 'numeric');
            app.ShiftingEditField.Limits = [0 Inf];
            app.ShiftingEditField.ValueChangedFcn = createCallbackFcn(app, @ShiftingEditFieldValueChanged, true);
            app.ShiftingEditField.Value = 570000000;
            app.ShiftingEditFieldLabel = uilabel(app.GridLayout6);
            app.ShiftingEditFieldLabel.Text = 'Shifting';
            app.ToleranceEditField = uieditfield(app.GridLayout6, 'numeric');
            app.ToleranceEditField.Limits = [0 Inf];
            app.ToleranceEditField.ValueChangedFcn = createCallbackFcn(app, @ToleranceEditFieldValueChanged, true);
            app.ToleranceEditField.Value = 100000000;
            app.ToleranceEditFieldLabel = uilabel(app.GridLayout6);
            app.ToleranceEditFieldLabel.Text = 'Tolerance';
            app.MinDistEditField = uieditfield(app.GridLayout6, 'numeric');
            app.MinDistEditField.Limits = [0 Inf];
            app.MinDistEditField.ValueChangedFcn = createCallbackFcn(app, @MinDistEditFieldValueChanged, true);
            app.MinDistEditField.Value = 10000000;
            app.MinDistEditFieldLabel = uilabel(app.GridLayout6);
            app.MinDistEditFieldLabel.Text = 'Min. Dist.';
            app.ThresholdEditField = uieditfield(app.GridLayout6, 'numeric');
            app.ThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdEditFieldValueChanged, true);
            app.ThresholdLabel = uilabel(app.GridLayout6);
            app.ThresholdLabel.Text = 'Threshold';
            app.OfPeaksSpinner = uispinner(app.GridLayout6);
            app.OfPeaksSpinner.Limits = [1 Inf];
            app.OfPeaksSpinner.ValueChangedFcn = createCallbackFcn(app, @OfPeaksSpinnerValueChanged, true);
            app.OfPeaksSpinner.Value = 4;
            app.OfPeaksSpinnerLabel = uilabel(app.GridLayout6);
            app.OfPeaksSpinnerLabel.Text = '# Of Peaks';
            app.SmthDegreeSpinner = uispinner(app.GridLayout6);
            app.SmthDegreeSpinner.Limits = [1 Inf];
            app.SmthDegreeSpinner.ValueChangedFcn = createCallbackFcn(app, @SmthDegreeSpinnerValueChanged, true);
            app.SmthDegreeSpinner.Value = 7;
            app.ZoningEditField = uieditfield(app.GridLayout6, 'text');
            app.ZoningEditField.ValueChangedFcn = createCallbackFcn(app, @ZoningEditFieldValueChanged, true);
            app.SmthDegreeSpinnerLabel = uilabel(app.GridLayout6);
            app.SmthDegreeSpinnerLabel.Text = 'Smth Degree';
            app.PartneringDropDown = uidropdown(app.GridLayout6);
            app.PartneringDropDown.Items = {'Shifting (D map)', 'Splitting (E map)', 'Naive (locations)'};
            app.PartneringDropDown.ValueChangedFcn = createCallbackFcn(app, @PartneringDropDownValueChanged, true);
            app.PartneringDropDown.Value = 'Splitting (E map)';
            app.PartneringDropDownLabel = uilabel(app.GridLayout6);
            app.PartneringDropDownLabel.Text = 'Partnering';
            app.GeneratingDropDown = uidropdown(app.GridLayout6);
            app.GeneratingDropDown.Items = {'Tallest, fail=average', 'Prominent', 'Closest', 'Avg. Tallest 2', 'Avg. Prom 2', 'Tallest', 'None'};
            app.GeneratingDropDown.ValueChangedFcn = createCallbackFcn(app, @GeneratingDropDownValueChanged, true);
            app.GeneratingDropDown.Value = 'None';
            app.GeneratingDropDownLabel = uilabel(app.GridLayout6);
            app.GeneratingDropDownLabel.Text = 'Generating';
            app.SmoothingDropDown = uidropdown(app.GridLayout6);
            app.SmoothingDropDown.Items = {'None', 'Laplacian Fix', 'Average 4', 'Gaussian', 'Blur Laplacian'};
            app.SmoothingDropDown.Value = 'None';
            app.ZoningDropDown = uidropdown(app.GridLayout6);
            app.ZoningDropDown.Items = {'None', 'Frequency', 'Value', 'Shift', 'Split', 'Frequency + Vals', 'Shift + Split'};
            app.ZoningDropDown.ValueChangedFcn = createCallbackFcn(app, @ZoningDropDownValueChanged, true);
            app.ZoningDropDown.Value = 'None';
            app.SmoothingDropDownLabel = uilabel(app.GridLayout6);
            app.SmoothingDropDownLabel.Text = 'Smoothing';
            app.SeedingDropDownLabel = uilabel(app.GridLayout6);
            app.SeedingDropDownLabel.Text = 'Seeding';
            app.SeedingDropDown = uidropdown(app.GridLayout6);
            app.SeedingDropDown.Items = {'Tallest Single', 'Tallest Average', 'Tallest Outermost', 'Leftmost', 'Rightmost', 'Innermost (smallest split)', 'Closest to Guess', 'Closest Height Difference'};
            app.SeedingDropDown.ValueChangedFcn = createCallbackFcn(app, @SeedingDropDownValueChanged, true);
            app.SeedingDropDown.Value = 'Tallest Single';
            app.ParameterDropDown = uidropdown(app.GridLayout6);
            app.ParameterDropDown.Items = {'D 111 (1)', 'E 111 (2)', 'Width 111 (3)', 'Width Dev 111 (4)', 'Contrast 111 (5)', 'Contrast Dev 111 (6)', 'D non111 (7)', 'E non111 (8)', 'Width non111 (9)', 'Width Dev non111 (10)', 'Contrast non111 (11)', 'Contrast Dev non111 (12)', 'Baseline (13)', 'Tries', 'Left Peak', 'Right Peak', 'Laplacian', 'Default'};
            app.ParameterDropDown.ValueChangedFcn = createCallbackFcn(app, @ParameterDropDownValueChanged, true);
            app.ParameterDropDown.Enable = 'off';
            app.ParameterDropDown.Value = 'Default';
            app.ParameterDropDownLabel = uilabel(app.GridLayout6);
            app.ParameterDropDownLabel.Enable = 'off';
            app.ParameterDropDownLabel.Text = 'Parameter';
            app.LowerLimitEditField = uieditfield(app.GridLayout6, 'numeric');
            app.LowerLimitEditField.ValueChangedFcn = createCallbackFcn(app, @LowerLimitEditFieldValueChanged, true);
            app.LowerLimitEditFieldLabel = uilabel(app.GridLayout6);
            app.LowerLimitEditFieldLabel.Text = 'Lower Limit';
            app.UpperLimitEditField = uieditfield(app.GridLayout6, 'numeric');
            app.UpperLimitEditField.ValueChangedFcn = createCallbackFcn(app, @UpperLimitEditFieldValueChanged, true);
            app.UpperLimitEditFieldLabel = uilabel(app.GridLayout6);
            app.UpperLimitEditFieldLabel.Text = 'Upper Limit';
            app.ZoningEditFieldLabel = uilabel(app.GridLayout6);
            app.ZoningEditFieldLabel.Text = 'Zoning';
            app.ZoningDropDown_2Label = uilabel(app.GridLayout6);
            app.ZoningDropDown_2Label.Text = 'Zoning';
            app.DefineROIButton = uibutton(app.GridLayout6, 'push');
            app.DefineROIButton.ButtonPushedFcn = createCallbackFcn(app, @DefineROIButtonPushed, true);
            app.DefineROIButton.Text = 'Define ROI';
            app.ShowROICheckBox = uicheckbox(app.GridLayout6);
            app.ShowROICheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowROICheckBoxValueChanged, true);
            app.ShowROICheckBox.Text = 'Show ROI';
            app.SmthSpanSpinner = uispinner(app.GridLayout6);
            app.SmthSpanSpinner.Step = 2;
            app.SmthSpanSpinner.Limits = [1 Inf];
            app.SmthSpanSpinner.ValueChangedFcn = createCallbackFcn(app, @SmthSpanSpinnerValueChanged, true);
            app.SmthSpanSpinner.Value = 41;
            app.SmthSpanLabel = uilabel(app.GridLayout6);
            app.SmthSpanLabel.Text = 'Smth Span';
            app.ClearROIButton = uibutton(app.GridLayout6, 'push');
            app.ClearROIButton.ButtonPushedFcn = createCallbackFcn(app, @ClearROIButtonPushed, true);
            app.ClearROIButton.Text = 'Clear ROI';
            app.PlotROIButton = uibutton(app.GridLayout6, 'push');
            app.PlotROIButton.ButtonPushedFcn = createCallbackFcn(app, @PlotROIButtonPushed, true);
            app.PlotROIButton.Text = 'Plot ROI';
            app.PreviewFindButton = uibutton(app.GridLayout6, 'push');
            app.PreviewFindButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewFindButtonPushed, true);
            app.PreviewFindButton.Text = 'Preview Find';
            app.SavePreviewButton = uibutton(app.GridLayout6, 'push');
            app.SavePreviewButton.ButtonPushedFcn = createCallbackFcn(app, @SavePreviewButtonPushed, true);
            app.SavePreviewButton.Text = 'Save Preview';
            app.Refine = uicheckbox(app.GridLayout6);
            app.Refine.ValueChangedFcn = createCallbackFcn(app, @RefineValueChanged, true);
            app.Refine.Text = '';
            app.Refine.Value = true;
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.PeakFindingTab = uitab(app.TabGroup);
            app.PeakFindingTab.Title = 'Peak Finding';
            app.GridLayout2 = uigridlayout(app.PeakFindingTab);
            app.SpectrumAxes = uiaxes(app.GridLayout2);
            title(app.SpectrumAxes, 'Signal')
            xlabel(app.SpectrumAxes, 'Frequency')
            ylabel(app.SpectrumAxes, 'Response')
            zlabel(app.SpectrumAxes, 'Z')
            app.DisplayAxes = uiaxes(app.GridLayout2);
            xlabel(app.DisplayAxes, 'X')
            ylabel(app.DisplayAxes, 'Y')
            zlabel(app.DisplayAxes, 'Z')
            app.DisplayAxes.ButtonDownFcn = createCallbackFcn(app, @handle, true);
            app.LineCutDropDown = uidropdown(app.GridLayout2);
            app.LineCutDropDown.Items = {'Pixel', 'Vertical', 'Horizontal'};
            app.LineCutDropDown.ValueChangedFcn = createCallbackFcn(app, @LineCutDropDownValueChanged, true);
            app.LineCutDropDown.Value = 'Pixel';
            app.LaplacianThresholdLabel = uilabel(app.GridLayout2);
            app.LaplacianThresholdLabel.Text = 'Current Laplacian Thresholds (MHz/pixel): ';
            app.HighlightErrorsButton = uibutton(app.GridLayout2, 'state');
            app.HighlightErrorsButton.ValueChangedFcn = createCallbackFcn(app, @HighlightErrorsButtonValueChanged, true);
            app.HighlightErrorsButton.Text = 'Highlight Errors';
            app.LockLaplacianButton = uibutton(app.GridLayout2, 'state');
            app.LockLaplacianButton.ValueChangedFcn = createCallbackFcn(app, @LockLaplacianButtonValueChanged, true);
            app.LockLaplacianButton.Text = 'Lock Laplacian Thresholds';
            app.ofChunksEditField = uieditfield(app.GridLayout2, 'numeric');
            app.ofChunksEditField.Limits = [1 256];
            app.ofChunksEditField.RoundFractionalValues = 'on';
            app.ofChunksEditField.Value = 3;
            app.GenChunkButton = uibutton(app.GridLayout2, 'push');
            app.GenChunkButton.ButtonPushedFcn = createCallbackFcn(app, @GenChunkButtonPushed, true);
            app.GenChunkButton.Text = 'Gen. Chunk';
            app.LeftButton = uibutton(app.GridLayout2, 'push');
            app.LeftButton.ButtonPushedFcn = createCallbackFcn(app, @LeftButtonPushed, true);
            app.LeftButton.Text = '<<';
            app.RightButton = uibutton(app.GridLayout2, 'push');
            app.RightButton.ButtonPushedFcn = createCallbackFcn(app, @RightButtonPushed, true);
            app.RightButton.Text = '>>';
            app.PreviousSettingsButton = uibutton(app.GridLayout2, 'state');
            app.PreviousSettingsButton.ValueChangedFcn = createCallbackFcn(app, @PreviousSettingsButtonValueChanged, true);
            app.PreviousSettingsButton.Text = 'Previous Settings';
            app.NormalizeCountsButton = uibutton(app.GridLayout2, 'state');
            app.NormalizeCountsButton.ValueChangedFcn = createCallbackFcn(app, @NormalizeCountsButtonValueChanged, true);
            app.NormalizeCountsButton.Text = 'Normalize Counts';
            app.SavetoBaseWorkspaceButton = uibutton(app.GridLayout2, 'push');
            app.SavetoBaseWorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @SavetoBaseWorkspaceButtonPushed, true);
            app.SavetoBaseWorkspaceButton.Text = 'Save to Base Workspace';
            app.chunksCheckBox = uicheckbox(app.GridLayout2);
            app.chunksCheckBox.ValueChangedFcn = createCallbackFcn(app, @chunksCheckBoxValueChanged, true);
            app.chunksCheckBox.Text = '(-)     # chunks';
            app.PixelDetailsTab = uitab(app.TabGroup);
            app.PixelDetailsTab.Title = 'Pixel Details';
            app.PixelValueAxes = uiaxes(app.PixelDetailsTab);
            title(app.PixelValueAxes, 'Change in Pixel Value')
            xlabel(app.PixelValueAxes, 'X')
            ylabel(app.PixelValueAxes, 'Y')
            zlabel(app.PixelValueAxes, 'Z')
            app.PixelValueTable = uitable(app.PixelDetailsTab);
            app.PixelValueTable.ColumnName = {'Parameter'; 'Value'};
            app.PixelValueTable.RowName = {};
            app.FileDataTable = uitable(app.PixelDetailsTab);
            app.FileDataTable.ColumnName = {'File'; 'Value'; 'X'};
            app.FileDataTable.RowName = {};
            app.FileDataTable.ColumnEditable = [false false false];
            app.GlobalActionsTab = uitab(app.TabGroup);
            app.GlobalActionsTab.Title = 'Global Actions';
            app.SlopeMapAxes = uiaxes(app.GlobalActionsTab);
            xlabel(app.SlopeMapAxes, 'X')
            ylabel(app.SlopeMapAxes, 'Y')
            zlabel(app.SlopeMapAxes, 'Z')
            app.SlopeMapAxes.ButtonDownFcn = createCallbackFcn(app, @SlopeMapAxesButtonDown, true);
            app.SlopeAxes = uiaxes(app.GlobalActionsTab);
            title(app.SlopeAxes, 'Slope')
            xlabel(app.SlopeAxes, 'X')
            ylabel(app.SlopeAxes, 'Value')
            zlabel(app.SlopeAxes, 'Z')
            app.SlopeFileDataTable = uitable(app.GlobalActionsTab);
            app.SlopeFileDataTable.ColumnName = {'File'; 'Value'; 'X'};
            app.SlopeFileDataTable.RowName = {};
            app.SlopeFileDataTable.ColumnEditable = [false false true];
            app.SlopeFileDataTable.CellEditCallback = createCallbackFcn(app, @SlopeFileDataTableCellEdit, true);
            app.AlignImagesButton = uibutton(app.GlobalActionsTab, 'push');
            app.AlignImagesButton.ButtonPushedFcn = createCallbackFcn(app, @AlignImagesButtonPushed, true);
            app.AlignImagesButton.Tooltip = {'Select files to align in the above list box, then click this button to align them with one another,'};
            app.AlignImagesButton.Text = 'Align Images';
            app.SavePlotDataButton = uibutton(app.GlobalActionsTab, 'push');
            app.SavePlotDataButton.ButtonPushedFcn = createCallbackFcn(app, @SavePlotDataButtonPushed, true);
            app.SavePlotDataButton.Text = 'Save Plot Data';
            app.CalculateSlopesButton = uibutton(app.GlobalActionsTab, 'push');
            app.CalculateSlopesButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateSlopesButtonPushed, true);
            app.CalculateSlopesButton.Text = 'Calculate Slopes';
            app.SlopeLabel = uilabel(app.GlobalActionsTab);
            app.SlopeLabel.Text = 'Slope';
            app.InterceptLabel = uilabel(app.GlobalActionsTab);
            app.InterceptLabel.Text = 'Intercept:';
            app.PassDownSplitButton = uibutton(app.GlobalActionsTab, 'push');
            app.PassDownSplitButton.ButtonPushedFcn = createCallbackFcn(app, @PassDownSplitButtonPushed, true);
            app.PassDownSplitButton.Text = 'Pass Down Split';
            app.PlottedValueDropDownLabel = uilabel(app.GlobalActionsTab);
            app.PlottedValueDropDownLabel.Text = 'Plotted Value';
            app.PlottedValueDropDown = uidropdown(app.GlobalActionsTab);
            app.PlottedValueDropDown.Items = {'Slope', 'R-squared', 'Intercept'};
            app.PlottedValueDropDown.ValueChangedFcn = createCallbackFcn(app, @PlottedValueDropDownValueChanged, true);
            app.PlottedValueDropDown.Value = 'Slope';
            app.FilestoUseListBox = uilistbox(app.GlobalActionsTab);
            app.FilestoUseListBox.Items = {};
            app.FilestoUseListBox.Multiselect = 'on';
            app.FilestoUseListBox.Value = {};
            app.FilestoUseListBoxLabel = uilabel(app.GlobalActionsTab);
            app.FilestoUseListBoxLabel.Text = 'Files to Use';
            app.DifferencefromIdealSwitchLabel = uilabel(app.GlobalActionsTab);
            app.DifferencefromIdealSwitchLabel.Text = 'Difference from Ideal';
            app.DifferencefromIdealSwitch = uiswitch(app.GlobalActionsTab, 'slider');
            app.DifferencefromIdealSwitch.ValueChangedFcn = createCallbackFcn(app, @DifferencefromIdealSwitchValueChanged, true);
            app.DifferencefromIdealSwitch.Value = 'On';
            app.DisplayAlignmentSwitchLabel = uilabel(app.GlobalActionsTab);
            app.DisplayAlignmentSwitchLabel.Text = 'Display Alignment';
            app.DisplayAlignmentSwitch = uiswitch(app.GlobalActionsTab, 'slider');
            app.OffsetEditFieldLabel = uilabel(app.GlobalActionsTab);
            app.OffsetEditFieldLabel.Text = 'Offset';
            app.OffsetEditField = uieditfield(app.GlobalActionsTab, 'numeric');
            app.ScriptRunsTab = uitab(app.TabGroup);
            app.ScriptRunsTab.Title = 'Script Runs';
            app.GridLayout5 = uigridlayout(app.ScriptRunsTab);
            app.ScriptOutputAxes = uiaxes(app.GridLayout5);
            title(app.ScriptOutputAxes, 'Output Plot')
            xlabel(app.ScriptOutputAxes, 'X')
            ylabel(app.ScriptOutputAxes, 'Y')
            zlabel(app.ScriptOutputAxes, 'Z')
            app.ScriptOutputAxes.ButtonDownFcn = createCallbackFcn(app, @handle_output, true);
            app.ScriptDisplayAxes = uiaxes(app.GridLayout5);
            xlabel(app.ScriptDisplayAxes, 'X')
            ylabel(app.ScriptDisplayAxes, 'Y')
            zlabel(app.ScriptDisplayAxes, 'Z')
            app.ScriptDisplayAxes.ButtonDownFcn = createCallbackFcn(app, @handle_script, true);
            app.PreviewButton = uibutton(app.GridLayout5, 'push');
            app.PreviewButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewButtonPushed, true);
            app.PreviewButton.Text = 'Preview';
            app.ApplyButton = uibutton(app.GridLayout5, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.AvailableScriptsListBoxLabel = uilabel(app.GridLayout5);
            app.AvailableScriptsListBoxLabel.Text = 'Available Scripts';
            app.AvailableScriptsListBox = uilistbox(app.GridLayout5);
            app.AvailableScriptsListBox.Items = {};
            app.AvailableScriptsListBox.Value = {};
            app.UploadButton = uibutton(app.GridLayout5, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.UploadButton.Text = 'Upload';
            app.InputFilesListBoxLabel = uilabel(app.GridLayout5);
            app.InputFilesListBoxLabel.Text = 'Input Files';
            app.InputFilesListBox = uilistbox(app.GridLayout5);
            app.InputFilesListBox.Items = {};
            app.InputFilesListBox.Multiselect = 'on';
            app.InputFilesListBox.Value = {};
            app.OutputFilesListBoxLabel = uilabel(app.GridLayout5);
            app.OutputFilesListBoxLabel.Text = 'Output Files';
            app.OutputFilesListBox = uilistbox(app.GridLayout5);
            app.OutputFilesListBox.Items = {};
            app.OutputFilesListBox.Multiselect = 'on';
            app.OutputFilesListBox.ValueChangedFcn = createCallbackFcn(app, @OutputFilesListBoxValueChanged, true);
            app.OutputFilesListBox.Value = {};
            app.ConfocalCodeTab = uitab(app.TabGroup);
            app.ConfocalCodeTab.Title = 'Confocal Code';
            app.GridLayout3 = uigridlayout(app.ConfocalCodeTab);
            app.StressAxes = uiaxes(app.GridLayout3);
            title(app.StressAxes, 'Plot')
            xlabel(app.StressAxes, 'X')
            ylabel(app.StressAxes, 'Y')
            zlabel(app.StressAxes, 'Z')
            app.ParametersTable = uitable(app.GridLayout3);
            app.ParametersTable.ColumnName = {'Fitting Parameter'; 'Value'; 'Fixed'};
            app.ParametersTable.RowName = {};
            app.ParametersTable.ColumnEditable = [false true true];
            app.ParametersTable.CellEditCallback = createCallbackFcn(app, @ParametersTableCellEdit, true);
            app.UpdateButton = uibutton(app.GridLayout3, 'push');
            app.UpdateButton.Text = 'Update';
            app.ExportButton = uibutton(app.GridLayout3, 'push');
            app.ExportButton.Text = 'Export';
            app.Button = uibutton(app.GridLayout3, 'state');
            app.Button.Text = 'Z';
            app.Button_2 = uibutton(app.GridLayout3, 'state');
            app.Button_2.Text = 'B';
            app.Button_3 = uibutton(app.GridLayout3, 'state');
            app.Button_3.Text = 'E';
            app.FitButton = uibutton(app.GridLayout3, 'push');
            app.FitButton.Text = 'Fit';
            app.Button_4 = uibutton(app.GridLayout3, 'state');
            app.Button_4.Text = 'S';
            app.OrientationButton = uibutton(app.GridLayout3, 'state');
            app.OrientationButton.Text = '100 Orientation';
            app.FunPlotsTab = uitab(app.TabGroup);
            app.FunPlotsTab.Title = 'Fun Plots';
            app.Axes3D = uiaxes(app.FunPlotsTab);
            title(app.Axes3D, ',')
            xlabel(app.Axes3D, 'X')
            ylabel(app.Axes3D, 'Y')
            zlabel(app.Axes3D, 'Z')
            app.HorizontalSpectrumAxes = uiaxes(app.FunPlotsTab);
            title(app.HorizontalSpectrumAxes, 'Horizontal Signal')
            xlabel(app.HorizontalSpectrumAxes, 'Peak Location')
            ylabel(app.HorizontalSpectrumAxes, 'Row')
            zlabel(app.HorizontalSpectrumAxes, 'Z')
            app.VerticalSpectrumAxes = uiaxes(app.FunPlotsTab);
            title(app.VerticalSpectrumAxes, 'Vertical Signal')
            xlabel(app.VerticalSpectrumAxes, 'Peak Location')
            ylabel(app.VerticalSpectrumAxes, 'Column')
            zlabel(app.VerticalSpectrumAxes, 'Z')
            app.LaplacianAxes = uiaxes(app.FunPlotsTab);
            title(app.LaplacianAxes, ',')
            xlabel(app.LaplacianAxes, 'X')
            ylabel(app.LaplacianAxes, 'Y')
            zlabel(app.LaplacianAxes, 'Z')
            app.ColormapEditorButton = uibutton(app.FunPlotsTab, 'push');
            app.ColormapEditorButton.Text = 'Colormap Editor';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = global_peaks_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end